classdef compareFr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        preImg       matlab.ui.control.UIAxes
        postImg      matlab.ui.control.UIAxes
        subtrImg     matlab.ui.control.UIAxes
        startpreFr   matlab.ui.control.Label
        stoppreFr    matlab.ui.control.Label
        startpostFr  matlab.ui.control.Label
        stoppostFr   matlab.ui.control.Label
        Label5       matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp % Description
    end

    properties (Access = public)
        subtrMean % Description
        preMean
        postMean
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, preM, postM, i)
            app.CallingApp=mainApp;
            app.UIFigure.Name=['Activation #' num2str(i)];
            app.Label5.Text=['Averaged over ' num2str(app.CallingApp.tMean) 's, corresponding to ' num2str(uint8(app.CallingApp.tMean/app.CallingApp.framePeriod)) ' frames'];
            xlim(app.preImg, [0 app.CallingApp.pixel]);
            ylim(app.preImg, [0 app.CallingApp.pixel]);
            xlim(app.postImg, [0 app.CallingApp.pixel]);
            ylim(app.postImg, [0 app.CallingApp.pixel]);
            xlim(app.subtrImg, [0 app.CallingApp.pixel]);
            ylim(app.subtrImg, [0 app.CallingApp.pixel]);
            app.startpreFr.Text=['Starting frame: ' num2str(preM(1))];
            app.stoppreFr.Text=['Stopping frame: ' num2str(preM(2))];
            app.startpostFr.Text=['Starting frame: ' num2str(postM(1))];
            app.stoppostFr.Text=['Stopping frame: ' num2str(postM(2))];
            app.preMean=mean(app.CallingApp.threshData(:,:,preM(1):preM(2)),3);
            app.postMean=mean(app.CallingApp.threshData(:,:,postM(1):postM(2)),3);
            app.subtrMean=app.postMean-app.preMean;
            preC=[min(min(app.preMean)), max(max(app.preMean))];
            postC=[min(min(app.postMean)), max(max(app.postMean))];
            subtrC=[min(min(app.subtrMean)), max(max(app.subtrMean))];
            imagesc(app.preImg,app.preMean);
            caxis(app.preImg,preC);
            imagesc(app.postImg,app.postMean);
            caxis(app.postImg,postC);
            imagesc(app.subtrImg,app.subtrMean);
            caxis(app.subtrImg,subtrC);
            colorbar(app.subtrImg)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.preImg = uiaxes(app.UIFigure);
            title(app.preImg, 'Mean pre stimulus')
            app.postImg = uiaxes(app.UIFigure);
            title(app.postImg, 'Mean post stimulus')
            app.subtrImg = uiaxes(app.UIFigure);
            title(app.subtrImg, 'Subtraction')
            app.startpreFr = uilabel(app.UIFigure);
            app.stoppreFr = uilabel(app.UIFigure);
            app.stoppreFr.Text = 'Label2';
            app.startpostFr = uilabel(app.UIFigure);
            app.startpostFr.Text = 'Label3';
            app.stoppostFr = uilabel(app.UIFigure);
            app.stoppostFr.Text = 'Label4';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Label5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compareFr(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end