classdef dFstack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        dFfig                          matlab.ui.control.UIAxes
        FrameSliderLabel               matlab.ui.control.Label
        FrameSlider                    matlab.ui.control.Slider
        Frame                          matlab.ui.control.NumericEditField
        playStack                      matlab.ui.control.Button
        filterfig                      matlab.ui.control.UIAxes
        SpatialaveragesizepixelEditFieldLabel  matlab.ui.control.Label
        SpatialaveragesizepixelEditField  matlab.ui.control.NumericEditField
        FrameSlider_2Label             matlab.ui.control.Label
        FrameSlider_2                  matlab.ui.control.Slider
        Frame2                         matlab.ui.control.NumericEditField
        PlaystackButton                matlab.ui.control.Button
        SaveasvideodF                  matlab.ui.control.Button
        Saveasvideoavg                 matlab.ui.control.Button
        FramesEditFieldLabel           matlab.ui.control.Label
        FramesEditField                matlab.ui.control.NumericEditField
        TemporalavaragesizesEditFieldLabel  matlab.ui.control.Label
        TemporalavaragesizesEditField  matlab.ui.control.NumericEditField
        ColormaxEditFieldLabel         matlab.ui.control.Label
        ColormaxEditField              matlab.ui.control.NumericEditField
        FramesEditField_2Label         matlab.ui.control.Label
        FramesEditField_2              matlab.ui.control.NumericEditField
        ColormaxEditField_2Label       matlab.ui.control.Label
        ColormaxEditField_2            matlab.ui.control.NumericEditField
        ColorminEditFieldLabel         matlab.ui.control.Label
        ColorminEditField              matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp % Description
    end

    properties (Access = public)
        currentFr % Description
        maxC
        minC
        maxC_avg
        minC_avg
        currentFr_avg
        filterdF
        xavg
        tavg
        t_avg
        Colmin
        Colmax
    end

    methods (Access = public)
    
        function spacetimefilter(app,xfilt, tfilt)
            app.filterdF=app.CallingApp.thdF_3D;
            h = fspecial('average',xfilt);
            for i=1:size(app.filterdF,3)
                app.filterdF(:,:,i) = filter2(h, app.CallingApp.thdF_3D(:,:,i));
            end

            for i=1:uint16(size(app.filterdF,1)/tfilt)
                if i==uint16(size(app.filterdF,1)/tfilt)
                    app.filterdF(:,:,i)=mean(app.filterdF(:,:,((i-1)*tfilt+1):end),3);
                else
                    app.filterdF(:,:,i)=mean(app.filterdF(:,:,((i-1)*tfilt+1):((i-1)*tfilt+tfilt)),3);
                end
                app.t_avg(i)=i*tfilt;
            end
            app.FrameSlider_2.Limits = [1 size(app.t_avg,2)];
            app.Frame2.Limits = [1 size(app.t_avg,2)];
            app.maxC_avg=max(max(max(app.filterdF)));
            app.minC_avg=min(min(min(app.filterdF)));
            imagesc(app.filterfig, app.filterdF(:,:,app.currentFr_avg))
            %caxis(app.filterfig,[app.minC_avg app.maxC_avg]);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)

            app.CallingApp=mainApp;
            app.FrameSlider.Limits = [1 app.CallingApp.newnumFrames];
            app.Frame.Limits = [1 app.CallingApp.newnumFrames];
            app.currentFr=1;
            app.Colmin=-200;
            app.Colmax=200;
            app.Frame.Value=app.currentFr;
            app.FrameSlider.Value=app.currentFr;
            xlim(app.dFfig, [0 app.CallingApp.pixel]);
            ylim(app.dFfig, [0 app.CallingApp.pixel]);
            xlim(app.filterfig, [0 app.CallingApp.pixel]);
            ylim(app.filterfig, [0 app.CallingApp.pixel]);
            app.maxC=max(max(max(app.CallingApp.thdF_3D)));
            app.minC=min(min(min(app.CallingApp.thdF_3D)));
            imagesc(app.dFfig, app.CallingApp.thdF_3D(:,:,app.currentFr))
            colorbar(app.dFfig)
            colorbar(app.filterfig)
            %caxis(app.dFfig,[app.minC app.maxC]);
            caxis(app.dFfig,[-100 200]);
            caxis(app.filterfig,[-100 200]);
            
            app.xavg=app.SpatialaveragesizepixelEditField.Value;
            app.tavg=uint16(app.TemporalavaragesizesEditField.Value/app.CallingApp.framePeriod);
            if app.tavg==0 
                app.tavg=1;
            end
            app.filterdF=app.CallingApp.thdF_3D;
            app.currentFr_avg=1;
            spacetimefilter(app,app.xavg,app.tavg);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)

            app.currentFr = uint32(app.FrameSlider.Value);
            app.Frame.Value=double(app.currentFr);
            imagesc(app.dFfig, app.CallingApp.thdF_3D(:,:,app.currentFr))
            %caxis(app.dFfig,[app.minC app.maxC]);
            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)

            app.currentFr = uint32(event.Value);
            app.Frame.Value=double(app.currentFr);
            imagesc(app.dFfig, app.CallingApp.thdF_3D(:,:,app.currentFr))
            %caxis(app.dFfig,[app.minC app.maxC]);
           
            
        end

        % Value changed function: Frame
        function FrameValueChanged(app, event)

            app.currentFr = uint32(app.Frame.Value);
            app.FrameSlider.Value=double(app.currentFr);
            imagesc(app.dFfig, app.CallingApp.thdF_3D(:,:,app.currentFr))
            %caxis(app.dFfig,[app.minC app.maxC]);
            
            
        end

        % Button pushed function: playStack
        function playStackButtonPushed(app, event)
            if app.playStack.Text=='Play stack'
                app.playStack.Text='Stop stack';
            else
                app.playStack.Text='Play stack';
            end
            while 1
                if app.playStack.Text=='Play stack'
                    break;
                end
                if app.currentFr==app.CallingApp.newnumFrames
                        app.currentFr=1;
                else
                    app.currentFr=app.currentFr+1;
                end
                app.FrameSlider.Value = double(app.currentFr);
                app.Frame.Value = double(app.currentFr);
                imagesc(app.dFfig, app.CallingApp.thdF_3D(:,:,app.currentFr))
                %caxis(app.dFfig,[app.minC app.maxC]);
               
                pause(1/app.FramesEditField.Value)
            end
        end

        % Value changed function: SpatialaveragesizepixelEditField
        function SpatialaveragesizepixelEditFieldValueChanged(app, event)

            app.xavg = app.SpatialaveragesizepixelEditField.Value;
            spacetimefilter(app,app.xavg,app.tavg);
        end

        % Value changed function: FrameSlider_2
        function FrameSlider_2ValueChanged(app, event)
            app.currentFr_avg = uint32(app.FrameSlider_2.Value);
            app.Frame2.Value=double(app.currentFr_avg);
            imagesc(app.filterfig, app.filterdF(:,:,app.currentFr_avg))
            %caxis(app.filterfig,[app.minC_avg app.maxC_avg]);
        end

        % Value changing function: FrameSlider_2
        function FrameSlider_2ValueChanging(app, event)
            app.currentFr_avg = uint32(event.Value);
            app.Frame2.Value=double(app.currentFr_avg);
            imagesc(app.filterfig, app.filterdF(:,:,app.currentFr_avg))
            %caxis(app.filterfig,[app.minC_avg app.maxC_avg]);
        end

        % Value changed function: Frame2
        function Frame2ValueChanged(app, event)
            app.currentFr_avg = uint32(app.Frame2.Value);
            app.FrameSlider_2.Value=double(app.currentFr_avg);
            imagesc(app.filterfig, app.filterdF(:,:,app.currentFr_avg))
            %caxis(app.filterfig,[app.minC_avg app.maxC_avg]);
        end

        % Button pushed function: PlaystackButton
        function PlaystackButtonPushed(app, event)
                if app.PlaystackButton.Text=='Play stack'
                    app.PlaystackButton.Text='Stop stack';
                else
                    app.PlaystackButton.Text='Play stack';
                end
                while 1
                    if app.PlaystackButton.Text=='Play stack'
                        break;
                    end
                    if app.currentFr_avg==size(app.t_avg,2)
                        app.currentFr_avg=1;
                    else
                        app.currentFr_avg=app.currentFr_avg+1;
                    end
                    app.FrameSlider_2.Value = double(app.currentFr_avg);
                    app.Frame2.Value = double(app.currentFr_avg);
                    imagesc(app.filterfig, app.filterdF(:,:,app.currentFr_avg))
                    %caxis(app.filterfig,[app.minC_avg app.maxC_avg]);
                    pause(1/app.FramesEditField_2.Value)
                end
        end

        % Button pushed function: SaveasvideodF
        function SaveasvideodFButtonPushed(app, event)
            selpath = uigetdir('C:\Users\mirko.zanon\Desktop');
            prompt = {'Video name:'};
            dlgtitle = 'Video name';
            nm=inputdlg(prompt,dlgtitle);
            nameV=cell2mat(nm);
            v = VideoWriter([selpath '\' nameV '.avi']);
            prompt = {'Frame rate:'};
            dlgtitle = 'Frame rate';
            fR=inputdlg(prompt,dlgtitle);
            frameR=str2num(cell2mat(fR));
            v.FrameRate=frameR;
            open(v);
            for i = 1:app.CallingApp.newnumFrames
                I=mat2gray(app.CallingApp.thdF_3D(:,:,i));
                imwrite(I,'frame.jpg');
                fr=imread('frame.jpg');
                writeVideo(v,fr);
            end
            delete('frame.jpg')
            close(v);
        end

        % Button pushed function: Saveasvideoavg
        function SaveasvideoavgButtonPushed(app, event)
            selpath = uigetdir('C:\Users\mirko.zanon\Desktop');
            prompt = {'Video name:'};
            dlgtitle = 'Video name';
            nm=inputdlg(prompt,dlgtitle);
            nameV=cell2mat(nm);
            v = VideoWriter([selpath '\' nameV '.avi']);
            prompt = {'Frame rate:'};
            dlgtitle = 'Frame rate';
            fR=inputdlg(prompt,dlgtitle);
            frameR=str2num(cell2mat(fR));
            v.FrameRate=frameR;
            open(v);
            for i = 1:app.CallingApp.numFrame_avg
                I=mat2gray(app.CallingApp.thdF3D_avg(:,:,i));
                imwrite(I,'frame.jpg');
                fr=imread('frame.jpg');
                writeVideo(v,fr);
            end
            delete('frame.jpg')
            close(v);
        end

        % Value changed function: TemporalavaragesizesEditField
        function TemporalavaragesizesEditFieldValueChanged(app, event)
            app.tavg = app.TemporalavaragesizesEditField.Value;
            spacetimefilter(app,app.xavg,app.tavg);
        end

        % Value changed function: ColormaxEditField
        function ColormaxEditFieldValueChanged(app, event)
            app.Colmax = app.ColormaxEditField.Value;
            caxis(app.dFfig,[app.Colmin app.Colmax]);
        end

        % Value changed function: ColormaxEditField_2
        function ColormaxEditField_2ValueChanged(app, event)
            value = app.ColormaxEditField_2.Value;
            caxis(app.filterfig,[-100 value]);
        end

        % Value changed function: ColorminEditField
        function ColorminEditFieldValueChanged(app, event)
            app.Colmin = app.ColorminEditField.Value;
            caxis(app.dFfig,[app.Colmin app.Colmax]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.dFfig = uiaxes(app.UIFigure);
            title(app.dFfig, 'dF')
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.Frame = uieditfield(app.UIFigure, 'numeric');
            app.Frame.ValueChangedFcn = createCallbackFcn(app, @FrameValueChanged, true);
            app.playStack = uibutton(app.UIFigure, 'push');
            app.playStack.ButtonPushedFcn = createCallbackFcn(app, @playStackButtonPushed, true);
            app.playStack.Text = 'Play stack';
            app.filterfig = uiaxes(app.UIFigure);
            title(app.filterfig, 'Space and time average dF')
            app.SpatialaveragesizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.SpatialaveragesizepixelEditFieldLabel.Text = 'Spatial average size [pixel]';
            app.SpatialaveragesizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpatialaveragesizepixelEditField.Limits = [0 Inf];
            app.SpatialaveragesizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @SpatialaveragesizepixelEditFieldValueChanged, true);
            app.SpatialaveragesizepixelEditField.Value = 1;
            app.FrameSlider_2Label = uilabel(app.UIFigure);
            app.FrameSlider_2Label.Text = 'Frame';
            app.FrameSlider_2 = uislider(app.UIFigure);
            app.FrameSlider_2.ValueChangedFcn = createCallbackFcn(app, @FrameSlider_2ValueChanged, true);
            app.FrameSlider_2.ValueChangingFcn = createCallbackFcn(app, @FrameSlider_2ValueChanging, true);
            app.Frame2 = uieditfield(app.UIFigure, 'numeric');
            app.Frame2.ValueChangedFcn = createCallbackFcn(app, @Frame2ValueChanged, true);
            app.PlaystackButton = uibutton(app.UIFigure, 'push');
            app.PlaystackButton.ButtonPushedFcn = createCallbackFcn(app, @PlaystackButtonPushed, true);
            app.PlaystackButton.Text = 'Play stack';
            app.SaveasvideodF = uibutton(app.UIFigure, 'push');
            app.SaveasvideodF.ButtonPushedFcn = createCallbackFcn(app, @SaveasvideodFButtonPushed, true);
            app.SaveasvideodF.Text = 'Save as video';
            app.Saveasvideoavg = uibutton(app.UIFigure, 'push');
            app.Saveasvideoavg.ButtonPushedFcn = createCallbackFcn(app, @SaveasvideoavgButtonPushed, true);
            app.Saveasvideoavg.Text = 'Save as video';
            app.FramesEditFieldLabel = uilabel(app.UIFigure);
            app.FramesEditFieldLabel.Text = 'Frame/s';
            app.FramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramesEditField.Limits = [1 Inf];
            app.FramesEditField.Value = 25;
            app.TemporalavaragesizesEditFieldLabel = uilabel(app.UIFigure);
            app.TemporalavaragesizesEditFieldLabel.Text = 'Temporal avarage size [s]';
            app.TemporalavaragesizesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemporalavaragesizesEditField.Limits = [0 Inf];
            app.TemporalavaragesizesEditField.ValueChangedFcn = createCallbackFcn(app, @TemporalavaragesizesEditFieldValueChanged, true);
            app.TemporalavaragesizesEditField.Value = 1;
            app.ColormaxEditFieldLabel = uilabel(app.UIFigure);
            app.ColormaxEditFieldLabel.Text = 'Color max';
            app.ColormaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColormaxEditField.ValueChangedFcn = createCallbackFcn(app, @ColormaxEditFieldValueChanged, true);
            app.ColormaxEditField.Value = 200;
            app.FramesEditField_2Label = uilabel(app.UIFigure);
            app.FramesEditField_2Label.Text = 'Frame/s';
            app.FramesEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FramesEditField_2.Limits = [1 Inf];
            app.FramesEditField_2.Value = 8;
            app.ColormaxEditField_2Label = uilabel(app.UIFigure);
            app.ColormaxEditField_2Label.Text = 'Color max';
            app.ColormaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ColormaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @ColormaxEditField_2ValueChanged, true);
            app.ColormaxEditField_2.Value = 200;
            app.ColorminEditFieldLabel = uilabel(app.UIFigure);
            app.ColorminEditFieldLabel.Text = 'Color min';
            app.ColorminEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColorminEditField.ValueChangedFcn = createCallbackFcn(app, @ColorminEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dFstack(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end