classdef intensityAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ROIsanalysisButton             matlab.ui.control.Button
        OdorstimulusparametersPanel    matlab.ui.container.Panel
        PrestimulussEditField_2Label   matlab.ui.control.Label
        PrestimulussEditField          matlab.ui.control.NumericEditField
        StimulussEditFieldLabel        matlab.ui.control.Label
        StimulussEditField             matlab.ui.control.NumericEditField
        InterstimulisEditFieldLabel    matlab.ui.control.Label
        InterstimulisEditField         matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel      matlab.ui.control.Label
        RepetitionsEditField           matlab.ui.control.NumericEditField
        Button_3                       matlab.ui.control.StateButton
        BaselinePanel                  matlab.ui.container.Panel
        startaverg                     matlab.ui.control.NumericEditField
        F0averagedbetweensEditFieldLabel  matlab.ui.control.Label
        F0averagedbetweensEditField    matlab.ui.control.NumericEditField
        TextArea                       matlab.ui.control.TextArea
        DF0TextAreaLabel               matlab.ui.control.Label
        DF0TextArea                    matlab.ui.control.TextArea
        F0fig                          matlab.ui.control.UIAxes
        Plot2DdFButton                 matlab.ui.control.Button
        LightstimulusparametersPanel   matlab.ui.container.Panel
        ListactivationframesseparetedbyspaceEditFieldLabel  matlab.ui.control.Label
        ListactivationframesEditField  matlab.ui.control.EditField
        EvaluateframesautoButton       matlab.ui.control.Button
        withthreasholdEditFieldLabel   matlab.ui.control.Label
        withthreasholdEditField        matlab.ui.control.NumericEditField
        Button_2                       matlab.ui.control.StateButton
        CutButton                      matlab.ui.control.Button
        ComparestimuliButton           matlab.ui.control.Button
        MeanLabel                      matlab.ui.control.Label
        RMSLabel                       matlab.ui.control.Label
        MeanLabel_2                    matlab.ui.control.Label
        RMSLabel_2                     matlab.ui.control.Label
        StdLabel                       matlab.ui.control.Label
        StdLabel_2                     matlab.ui.control.Label
        CrosscorrelationButton         matlab.ui.control.Button
        wcoherenceButton               matlab.ui.control.Button
        Iprofile                       matlab.ui.control.UIAxes
        Fcut                           matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    properties (Access = public)
        preStim
        durationStim
        interStim
        repStim
        removeStim
        actStartFr
        actTimes
        delay
        newnumFrames
        currentData
        currentThreshData
        currentFr
        pixel
        framePeriod
        thdF
        thdF_3D
        BT
        BTstart
        ROI
        posROI
        dFROI
        thF0_3D
        thF0_2D
        name
        imRef
        mod
        actFr
        duratFr
        tMean
        resizeRef
        xp
        yp
        lc
        pk
    end
    
    methods (Access = private)
        
        function removal(app)
            preFrames=uint16(app.preStim/app.CallingApp.framePeriod);
            durationFrames=uint16(app.durationStim/app.CallingApp.framePeriod)+1;
            interFrames=uint16(app.interStim/app.CallingApp.framePeriod);
            cutData=double(app.CallingApp.data);
            cutThreshData=double(app.CallingApp.threshData);
            app.newnumFrames=app.CallingApp.frames;
            if app.mod==0||app.mod==1
                disp('Data not cut')
            else
                app.actTimes=(uint32(app.actStartFr)-1)*app.framePeriod;
                delayT=app.duratFr*app.framePeriod;
                app.delay=zeros(size(app.actTimes,2)+1,1);
                for i=2:size(app.delay,1)
                    app.delay(i)=app.delay(i-1)+delayT(i-1);
                end
                cutData(:,:,uint32(app.actFr))=[];
                cutThreshData(:,:,uint32(app.actFr))=[];
                app.newnumFrames=size(cutData,3);
                disp(['Deleted ' num2str(size(app.actFr,2)) ' activation frames'])
            end
            app.currentData=cutData;
            app.currentThreshData=cutThreshData;
            app.resizeRef=imresize(app.currentThreshData, [50 50]);
            plotProfile(app);
            
        end
        
        function plotProfile(app)
            
            profile=squeeze(mean(mean(app.currentData)));
            originalThreshprofile=squeeze(mean(mean(app.CallingApp.threshData)));
            threshprofile=squeeze(mean(mean(app.currentThreshData)));
            BF=uint32(app.BT/app.framePeriod);
            BFstart=uint32(app.BTstart/app.framePeriod);
            if BF==0
                BF=1;
            elseif BF>app.newnumFrames
                BF=app.newnumFrames;
            end
            if BFstart==0
                BFstart=1;
            elseif BFstart>app.newnumFrames
                BFstart=app.newnumFrames;
            end
            F0=mean(profile(BFstart:BF)); %average intensity of the first seconds of baseline acquisition
            thF0=mean(threshprofile(BFstart:BF));
            app.DF0TextArea.Value=num2str(thF0,'%.1f');
            dF=(profile-F0)/F0*100; %dF/F(%)
            originalthdF=(originalThreshprofile-thF0)/thF0*100;
            app.thdF=(threshprofile-thF0)/thF0*100;
            [cw,freq]=cwt(app.thdF, 1/app.framePeriod);
            h=imagesc(app.UIAxes,abs(cw));
            yticks = round(linspace(1,size(freq,1),5));
            yticklabels={round(freq(yticks),2)};
            set(app.UIAxes, 'YTick', yticks, 'YTickLabel', yticklabels);
            set(h, 'XData', app.CallingApp.framePeriod:app.CallingApp.framePeriod:app.newnumFrames*app.CallingApp.framePeriod);
            %app.CallingApp.framePeriod:app.CallingApp.framePeriod:app.newnumFrames*app.CallingApp.framePeriod
            BFdata=app.currentThreshData(:,:,BFstart:BF);
            app.thF0_2D=mean(BFdata,3);
            xlim(app.F0fig, [0 app.pixel]);
            ylim(app.F0fig, [0 app.pixel]);
            imagesc(app.F0fig,app.thF0_2D)
            app.thF0_3D=repmat(app.thF0_2D,[1 1 size(app.currentThreshData,3)]);
            app.thdF_3D=(app.currentThreshData-app.thF0_3D)./app.thF0_3D*100;
            
            %             % time average
            %             fr_avg=uint16(app.time_avg/app.CallingApp.framePeriod);
            %              if fr_avg==0
            %                  fr_avg=1;
            %              end
            %
            %             for i=1:uint16(size(thdF,1)/fr_avg)
            %                 if i==uint16(size(thdF,1)/fr_avg)
            %                     thdF_avg(i)=mean(thdF(((i-1)*fr_avg+1):end));
            %                     app.thdF3D_avg(:,:,i)=mean(app.thdF_3D(:,:,((i-1)*fr_avg+1):end),3);
            %                 else
            %                     thdF_avg(i)=mean(thdF(((i-1)*fr_avg+1):((i-1)*fr_avg+fr_avg)));
            %                     app.thdF3D_avg(:,:,i)=mean(app.thdF_3D(:,:,((i-1)*fr_avg+1):((i-1)*fr_avg+fr_avg)),3);
            %                 end
            %                 t_avg(i)=i*app.time_avg;
            %             end
            %             app.numFrame_avg=size(t_avg,2);
            
            plot(app.Iprofile, 1:app.CallingApp.frames, originalThreshprofile);
            xlabel(app.Iprofile, 'frame');
            ylabel(app.Iprofile, 'F [a.u.]');
            meanprof=mean(originalThreshprofile);
            rmsprof=rms(originalThreshprofile);
            stdprof=std(originalThreshprofile);
            app.MeanLabel_2.Text=['Mean: ' num2str(meanprof)];
            app.RMSLabel_2.Text=['RMS: ' num2str(rmsprof)];
            app.StdLabel_2.Text=['Std: ' num2str(stdprof)];
            plot(app.Fcut, app.CallingApp.framePeriod:app.CallingApp.framePeriod:app.newnumFrames*app.CallingApp.framePeriod, app.thdF);
            yl=ylim(app.Fcut);
            if app.mod==1||app.mod==3
                for i=1:app.repStim
                    pos=[app.preStim+(i-1)*(app.interStim+app.durationStim), yl(1)+0.02*yl(2), app.durationStim, yl(2)-yl(1)];
                    rectangle(app.Fcut,'Position', pos, 'EdgeColor', 'r')
                end
            end
            if app.mod==2||app.mod==3
                for i=1:size(app.actTimes,2)
                    line(app.Fcut, [app.actTimes(i)-app.delay(i),app.actTimes(i)-app.delay(i)],ylim(app.Fcut),'Color','r')
                end
            end
            meandF=mean(app.thdF);
            rmsdF=rms(app.thdF);
            stddF=std(app.thdF);
            app.MeanLabel.Text=['Mean: ' num2str(meandF)];
            app.RMSLabel.Text=['RMS: ' num2str(rmsdF)];
            app.StdLabel.Text=['Std: ' num2str(stddF)];
        end


        function updateActFr(app)
            app.actStartFr=[];
            app.actStartFr(1)=uint32(app.PrestimulussEditField.Value/app.framePeriod);
            for i=2:app.RepetitionsEditField.Value
                app.actStartFr(i)=uint32((app.PrestimulussEditField.Value+(i-1)*(app.StimulussEditField.Value+app.InterstimulisEditField.Value))/app.framePeriod);
            end
            app.duratFr=zeros(size(app.actStartFr,2));
        end
        
        function correlationCut(app,num,start,stop)
            for k=1:length(app.ROI)
                for l=1:length(app.ROI)
                    [R,P] = corrcoef(squeeze(app.ROI(k).signal(num,start:stop)),squeeze(app.ROI(l).signal(num,start:stop)));
                    [C,L]=xcorr(squeeze(app.ROI(k).signal(num,start:stop)),squeeze(app.ROI(l).signal(num,start:stop)),'normalized');
                    corrmat(k,l)=R(1,2);
                    probmat(k,l)=P(1,2);
                    [crosscorr(k,l), ind]=max(C);
                    lags(k,l)=L(ind).*app.framePeriod;
                end
            end 
            %Plot
            figure('Name',['Correlation matrix for stimulus #' num2str(num)])
            title(['Correlation matrix between ROIs for stimulus #' num2str(num)])
            imagesc(corrmat(:,:))
            caxis([0 1])
            colormap jet
            colorbar
            
            figure('Name',['Cross-correlations matrix for stimulus #' num2str(num)])
            title(['Cross-correlation matrix between ROIs for stimulus #' num2str(num)])
            imagesc(crosscorr(:,:))
            for k=1:length(app.ROI)
                for l=1:length(app.ROI)
                    lg=round(lags(k,l),2);
                    text(k,l,[num2str(lg) ' s']);
                end
            end
            caxis([0 1])
            colormap jet
            colorbar
            title(['Cross-correlation matrix between ROIs for stimulus #' num2str(num)])
        end
        
        function correlationCutTot(app)
            prompt = {'Enter pre-stimulus avraging time [s]:','Enter post-starting stimulus averaging time [s]:'};
            dlgtitle = 'Averaging windows';
            dims = [1 45];
            definput = {'5','6'};
            answer = str2num(cell2mat(inputdlg(prompt,dlgtitle,dims,definput)));
            preF=uint32(answer(1)/app.framePeriod);
            postF=uint32(answer(2)/app.framePeriod);
            
            for j=1:size(app.actStartFr,2)
                cutResize(:,:,:,j)=app.resizeRef(:,:,app.actStartFr(j)-preF:app.actStartFr(j)+postF);
                if app.mod==1||app.mod==3
                    BF(j)=app.actStartFr(j)-2;
                    BFstart(j)=app.actStartFr(j)-double(preF);
                end
                if app.mod==2||app.mod==3
                    stimrefT(j)=app.actTimes(j)-app.delay(j);
                    stimrefF(j)=uint32(stimrefT(j)/app.framePeriod);
                    BF(j)=stimrefF(j)-2;
                    BFstart(j)=double(stimrefF(j))-double(preF);
                end
                for l=1:50
                    for k=1:50
                        xx=squeeze(cutResize(l,k,:,j));
                        yy=squeeze(cutResize(app.xp,app.yp,:,j));
                        [R,P] = corrcoef(xx,yy);
                        [C,L]=xcorr(xx,yy,'normalized');
                        corrmat(l,k)=R(1,2);
                        probmat(l,k)=P(1,2);
                        [crosscorr(l,k), ind]=max(C);
                        lags(l,k)=L(ind).*app.framePeriod;
                    end
                end
                
                %Plot
                figure('Name',['Correlations matrix for stimulus #' num2str(j)])
                imagesc(corrmat(:,:))
                caxis([0 1])
                colormap jet
                colorbar
                title(['Correlation matrix between points for stimulus #' num2str(j)])
                
                figure('Name',['Cross-correlations for stimulus #' num2str(j)])
                suptitle(['Cross-correlations for stimulus #' num2str(j)])
                subplot(1,2,1)
                imagesc(crosscorr(:,:))
                caxis([0 1])
                colormap jet
                colorbar
                title('Maximum cross-correlation')
                subplot(1,2,2)
                imagesc(lags(:,:))
                colormap jet
                colorbar
                title('Lags @maximum correlation')
            end
        end
        
        function correlation(app)
            for k=1:length(app.ROI)
                for l=1:length(app.ROI)
                    [R,P] = corrcoef(squeeze(app.ROI(k).signal),squeeze(app.ROI(l).signal));
                    [C,L]=xcorr(squeeze(app.ROI(k).signal),squeeze(app.ROI(l).signal),'normalized');
                    corrmat(k,l)=R(1,2);
                    probmat(k,l)=P(1,2);
                    [crosscorr(k,l), ind]=max(C);
                    lags(k,l)=L(ind).*app.framePeriod;
                end
            end
            %Plot
            figure('Name',['Correlations matrix'])
            imagesc(corrmat(:,:))
            caxis([0 1])
            colormap jet
            colorbar
            title(['Correlation matrix between ROIs'])
            figure('Name',['Cross-correlations matrix'])
            imagesc(crosscorr(:,:))
            for k=1:length(app.ROI)
                for l=1:length(app.ROI)
                    lg=round(lags(k,l),2,'significant');
                    text(k,l,[num2str(lg) ' s']);
                end
            end
            caxis([0 1])
            colormap jet
            colorbar
            title(['Cross-correlation matrix between ROIs'])
            
%             figure(3345566)
%             G = graph(corrmat,'upper');
%             G.Edges.LWidths = 7*G.Edges.Weight/max(G.Edges.Weight);
%             p=plot(G);
%             p.LineWidth = G.Edges.LWidths;
        end
        
        function filtered=filterData(app)
            %Dynamic background filter window
            wbkg=500;%window size for dynamic bkg filtering
            fbkg=(1/wbkg)*ones(1,wbkg);
            %Spatial average filter window
            wspace=6;
            hdata = ones(wspace,wspace)/wspace^2;
            %Temporal average window
            wtime=2;
            ftime=(1/wtime)*ones(1,wtime);
            % to maintain continuity in level and slope
            edge1=flip(app.currentThreshData(:,:,1:wbkg,:),3);  % flip signal along 3rd dimension
            edge2=flip(app.currentThreshData(:,:,end-wbkg+1:end,:),3);
            dynbkgcorr = filter(fbkg,1,cat(3,edge1,app.currentThreshData,edge2),[],3);%filter along 3rd temporal dimension
            clear edge1 edge2
            dynbkg = double(dynbkgcorr(:,:,wbkg+1:end-wbkg,:));
            clear dynbkgcorr 
            filtered=imfilter((double(app.currentThreshData)-dynbkg)./dynbkg,hdata);%filter along 1st two spatial dimensions
            clear dynbkg
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            app.CallingApp=mainApp;
            app.name=app.CallingApp.name;
            app.imRef=app.CallingApp.imRef;
            app.mod=0;
            app.preStim=app.PrestimulussEditField.Value;
            app.durationStim=app.StimulussEditField.Value;
            app.interStim=app.InterstimulisEditField.Value;
            app.repStim=app.RepetitionsEditField.Value;
            app.currentData=double(app.CallingApp.data);
            app.currentThreshData=double(app.CallingApp.threshData);
            app.newnumFrames=app.CallingApp.frames;
            app.currentFr=app.CallingApp.currentFr;
            app.pixel=app.CallingApp.pixel;
            app.framePeriod=app.CallingApp.framePeriod;
            app.UIFigure.Name=['Activation profile ' app.name];
            app.BT=app.F0averagedbetweensEditField.Value;
            app.startaverg.Limits = [0 app.F0averagedbetweensEditField.Value];
            app.BTstart=app.startaverg.Value;
            app.resizeRef=imresize(app.currentThreshData, [50 50]);
            app.lc={};
            app.pk={};
            plotProfile(app);
        end

        % Value changed function: PrestimulussEditField
        function PrestimulussEditFieldValueChanged(app, event)
            app.preStim = app.PrestimulussEditField.Value;
            updateActFr(app)
            removal(app);
        end

        % Value changed function: StimulussEditField
        function StimulussEditFieldValueChanged(app, event)
            app.durationStim = app.StimulussEditField.Value;
            updateActFr(app)
            removal(app);
        end

        % Value changed function: InterstimulisEditField
        function InterstimulisEditFieldValueChanged(app, event)
            app.interStim = app.InterstimulisEditField.Value;
            updateActFr(app)
            removal(app);
        end

        % Value changed function: RepetitionsEditField
        function RepetitionsEditFieldValueChanged(app, event)
            app.repStim = app.RepetitionsEditField.Value;
            updateActFr(app)
            removal(app);
        end

        % Button pushed function: ROIsanalysisButton
        function ROIsanalysisButtonPushed(app, event)
            %draw ROIs tool
            keep = questdlg('Would you like to keep previous ROIs?', 'ROIs selection', 'Ok', 'No','Ok');
            if keep=='No'
                markp=figure(1000);
                markp.Position=[40 620 560 420];
                set(markp,'Name',['Mark point ref - ' app.name]);
                imagesc(app.imRef(:,:,1:3))
                title(['Mark point reference']);
                figure(1);
                imagesc(sum(app.currentThreshData,3));
                title('Select ROI:')
                color = lines;
                hold on
                ButtonHandle = uicontrol('Style', 'PushButton','String', 'End','Callback', 'delete(gcf)');
                counter=0;
                app.ROI=[];
                while 1
                    h = drawfreehand;
                    counter=counter+1;
                    if  ~ishandle(ButtonHandle)
                        break
                    end
                    app.ROI(counter).mask = createMask(h);
                    app.ROI(counter).border = cell2mat(bwboundaries(app.ROI(counter).mask));
                    app.ROI(counter).center = mean(app.ROI(counter).border);
                    [row,col]=find(app.ROI(counter).border==1);
                    app.ROI(counter).area=[col,row];
                    plot(col,row)
                end
            end
            close all;
            %data report modality
            dlgTitle    = 'Baseline';
            dlgQuestion = 'How do you want to analize your responces?';
            inp = questdlg(dlgQuestion,dlgTitle,'Together','Separate', 'Averaged','Together');
            
            if inp=='Together' %overall profile
                BF=uint32(app.BT/app.framePeriod);
                BFstart=uint32(app.BTstart/app.framePeriod);
                if BFstart==0
                    BFstart=1;
                end
                for k = 1:length(app.ROI)
                    %ROIthF0_3D=repmat(app.thF0_2D.*app.ROI(k).mask,[1 1 size(app.currentThreshData,3)]);
                    roimask=repmat(app.ROI(k).mask,[1 1 size(app.currentThreshData,3)]);
                    accept=app.currentThreshData.*roimask;
                    sumAcc=squeeze(sum(sum(accept)));
                    pixelMask=sum(sum(app.ROI(k).mask));
                    for j=1:size(accept,3)
                        signal(j)=sumAcc(j)/pixelMask;
                    end
                    BLsignal=mean(signal(BFstart:BF));
                    app.ROI(k).signal=(signal-BLsignal)/BLsignal*100;
                    app.ROI(k).filteredsignal = lowpass(app.ROI(k).signal, 0.15);
                    %bkg=mean(app.dFROI(uint16(app.BTstart/app.framePeriod)+1:uint16(app.BT/app.framePeriod)));
                    
                    prof(k)=figure('Name', ['ROI ' num2str(k) ' ' app.name]);
                    figure(prof(k));
                    subplot(2,1,1)
                    plot(app.framePeriod:app.framePeriod:app.newnumFrames*app.framePeriod, app.ROI(k).signal);
                    hold on;
                    plot(app.framePeriod:app.framePeriod:app.newnumFrames*app.framePeriod, app.ROI(k).filteredsignal,'b',"LineWidth",1.1);
                    title(['ROI ' num2str(k)])
                    xlabel('t [s]')
                    ylabel('dF/F0 [%]')
                    yl=ylim;
                    if app.mod==1||app.mod==3
                        for i=1:app.repStim
                            pos=[app.preStim+(i-1)*(app.interStim+app.durationStim), yl(1)+0.02*yl(2), app.durationStim, yl(2)-yl(1)];
                            rectangle('Position', pos, 'EdgeColor', 'r',"LineWidth",1.1)
%                             line([app.preStim+(i-1)*(app.interStim+app.durationStim),app.preStim+(i-1)*(app.interStim+app.durationStim)+app.durationStim],[yl(1)+0.05*yl(2) yl(1)+0.05*yl(2)],'Color','r',"LineWidth",2)
%                             line([app.preStim+(i-1)*(app.interStim+app.durationStim),app.preStim+(i-1)*(app.interStim+app.durationStim)+app.durationStim],[yl(2)-0.05*yl(2) yl(2)-0.05*yl(2)],'Color','r',"LineWidth",2)
                        end
                    end
                    if app.mod==2||app.mod==3
                        for i=1:size(app.actTimes,2)
                            line([app.actTimes(i)-app.delay(i),app.actTimes(i)-app.delay(i)],ylim,'Color','r')
                        end
                    end
                    subplot(2,1,2)
                    [cw,freq]=cwt(app.ROI(k).signal, 1/app.framePeriod);
                    h=imagesc(abs(cw));
                    yticks = round(linspace(1,size(freq,1),5));
                    yticklabels={round(freq(yticks),2)};
                    set(gca, 'YTick', yticks, 'YTickLabel', yticklabels);
                    set(h, 'XData', app.CallingApp.framePeriod:app.CallingApp.framePeriod:app.newnumFrames*app.CallingApp.framePeriod);
                    axis image;
                    title('Power spectrum')
                    xlabel('t [s]')
                    ylabel('f [Hz]')
                end
                froi=figure(2000);
                set(froi,'Name',['ROIs position ' app.name]);
                froi.Position=[40 80 560 420];
                imagesc(sum(app.currentThreshData,3));
                title('ROIs')
                hold on
                for k = 1:length(app.ROI)
                    figure(froi)
                    plot(app.ROI(k).border(:,2),app.ROI(k).border(:,1),'k','Linewidth',1)
                    text(app.ROI(k).center(2), app.ROI(k).center(1),sprintf(string(k)),'HorizontalAlignment', 'center','VerticalAlignment', 'middle');
                end
                correlation(app)
                
            elseif inp=='Separate' %stimulus-localized profile, local baseline
                prompt = {'Enter pre-stimulus avraging time [s]:','Enter post-starting stimulus averaging time [s]:'};
                dlgtitle = 'Averaging windows';
                dims = [1 45];
                definput = {'5.','6.'};
                answer = str2num(cell2mat(inputdlg(prompt,dlgtitle,dims,definput)));
                preF=uint32(answer(1)/app.framePeriod);
                postF=uint32(answer(2)/app.framePeriod);
                dlgTitle    = 'Plot';
                dlgQuestion = 'Do you want to plot all ROIs profiles together?';
                pltROI = questdlg(dlgQuestion,dlgTitle,'Together','Separate','Together');
                if preF==0
                    preF=1;
                end
                if postF==0
                    postF=1;
                end
                for k = 1:length(app.ROI)
                    roimask=repmat(app.ROI(k).mask,[1 1 size(app.currentThreshData,3)]);
                    accept=app.currentThreshData.*roimask;
                    sumAcc=squeeze(sum(sum(accept)));
                    pixelMask=sum(sum(app.ROI(k).mask));
                    for l=1:size(accept,3)
                        signal(l)=sumAcc(l)/pixelMask;
                    end
                    app.ROI(k).signal=zeros(size(app.actStartFr,2),size(signal,2));
                    for j=1:size(app.actStartFr,2)
                        if app.mod==1||app.mod==3
                            BF=app.actStartFr(j)-2;
                            BFstart=app.actStartFr(j)-double(preF);
                            BLsignal=mean(signal(BFstart:BF));
                            app.ROI(k).signal(j,:)=(signal-BLsignal)/BLsignal*100;
                        end
                        if app.mod==2||app.mod==3
                            stimrefT(j)=app.actTimes(j)-app.delay(j);
                            stimrefF(j)=uint32(stimrefT(j)/app.framePeriod);
                            BF=stimrefF(j)-2;
                            BFstart=double(stimrefF(j))-double(preF);
                            BLsignal=mean(signal(BFstart:BF));
                            app.ROI(k).signal(j,:)=(signal-BLsignal)/BLsignal*100;
                        end
                        
                        if pltROI=='Separate'
                            prof(j,k)=figure('Name', ['ROI ' num2str(k) ' ' app.name]);
                            figure(prof(j,k));
                            title(['ROI ' num2str(k) ', stimulus #' num2str(j)])
                            xlabel('t [s]')
                            ylabel('dF/F0 [%]')
                            yl=ylim;
                            if app.mod==1||app.mod==3
                                tCut=double((app.actStartFr(j)-preF)*app.framePeriod):app.framePeriod:double((app.actStartFr(j)+postF)*app.framePeriod);
                                yRoi=app.ROI(k).signal(j,(app.actStartFr(j)-preF):(app.actStartFr(j)+postF));
                                minlen = min(length(tCut), length(yRoi));
                                plot(tCut(1:minlen), yRoi(1:minlen));
                                pos=[app.preStim+(j-1)*(app.interStim+app.durationStim), yl(1)+0.02*yl(2), app.durationStim, yl(2)-yl(1)];
                                rectangle('Position', pos, 'EdgeColor', 'r')
                                title(['ROI ' num2str(k) ', stimulus #' num2str(j)])
                                xlabel('t [s]')
                                ylabel('dF/F0 [%]')
                                yl=ylim;
                            end
                            if app.mod==2||app.mod==3
                                tCut=double((stimrefF(j)-preF)*app.framePeriod):app.framePeriod:double((stimrefF(j)+postF)*app.framePeriod);
                                yRoi=app.ROI(k).signal(j,stimrefF(j)-preF:stimrefF(j)+postF);
                                minlen = min(length(tCut), length(yRoi));
                                plot(tCut(1:minlen), yRoi(1:minlen));
                                title(['ROI ' num2str(k) ', stimulus #' num2str(j)])
                                xlabel('t [s]')
                                ylabel('dF/F0 [%]')
                                yl=ylim;
                                line([stimrefT(j),stimrefT(j)],ylim,'Color','r')
                            end
                        end
                    end
                end
                
                if pltROI=='Together'
                    for j=1:size(app.actStartFr,2)
                        prof(j)=figure('Name', ['ROIs profiles for stimulus #' num2str(j) ' ' app.name]);
                        figure(prof(j));
                        hold on;
                        title(['ROIs profiles for stimulus #' num2str(j)])
                        xlabel('t [s]')
                        ylabel('dF/F0 [%]')
                        yl=ylim;
                        for r = 1:length(app.ROI)
                            if app.mod==1||app.mod==3
                                tCut=double((app.actStartFr(j)-preF)*app.framePeriod):app.framePeriod:double((app.actStartFr(j)+postF)*app.framePeriod);
                                yRoi=app.ROI(r).signal(j,(app.actStartFr(j)-preF):(app.actStartFr(j)+postF));
                                minlen = min(length(tCut), length(yRoi));
                                plot(tCut(1:minlen), yRoi(1:minlen));
                                legend;
                                hold on;
                                pos=[app.preStim+(j-1)*(app.interStim+app.durationStim), yl(1)+0.02*yl(2), app.durationStim, yl(2)-yl(1)];
                                rectangle('Position', pos, 'EdgeColor', 'r')
                            end
                            if app.mod==2||app.mod==3
                                stimrefT(j)=uint32(app.actTimes(j)-app.delay(j));
                                stimrefF(j)=uint32(stimrefT(j)/app.framePeriod);
                                tCut=double((stimrefF(j)-preF)*app.framePeriod):app.framePeriod:double((stimrefF(j)+postF)*app.framePeriod);
                                yRoi=app.ROI(r).signal(j,stimrefF(j)-preF:stimrefF(j)+postF);
                                %yRoi=normalize(lowpass(yRoi, 0.4),'range');
                                minlen = min(length(tCut), length(yRoi));
                                plot(tCut(1:minlen), yRoi(1:minlen));
                                legend;
                                hold on;                  
                            end
                        end
                        legend('AutoUpdate','off')
                        line([stimrefT(j),stimrefT(j)],ylim,'Color','r')
                    end
                end
                
                for j=1:size(app.actStartFr,2)
                    if app.mod==1
                        correlationCut(app,j,app.actStartFr(j)-preF,app.actStartFr(j)+postF)
                    else
                        correlationCut(app,j,stimrefF(j)-preF,stimrefF(j)+postF)
                    end
                end
                
            elseif inp=='Averaged' %averaged profile over all trials
                prompt = {'Enter pre-stimulus avraging time [s]:','Enter post-starting stimulus averaging time [s]:'};
                dlgtitle = 'Averaging windows';
                dims = [1 45];
                definput = {'5','6'};
                answer = str2num(cell2mat(inputdlg(prompt,dlgtitle,dims,definput)));
                preF=double(answer(1)/app.framePeriod); %changed from uint16 to double per plot
                postF=double(answer(2)/app.framePeriod);
                if preF==0
                    preF=1;
                end
                if postF==0
                    postF=1;
                end
                for k = 1:length(app.ROI)
%                     preF=30;
%                     postF=60;
                    roimask=repmat(app.ROI(k).mask,[1 1 size(app.currentThreshData,3)]);
                    accept=app.currentThreshData.*roimask;
                    sumAcc=squeeze(sum(sum(accept)));
                    pixelMask=sum(sum(app.ROI(k).mask));
                    for l=1:size(accept,3)
                        signal(l)=sumAcc(l)/pixelMask;
                    end
                    for j=1:size(app.actStartFr,2)
                        signalROI(:,j)=signal(app.actStartFr(j)-preF:app.actStartFr(j)+postF);
                    end
                    meanRoi=mean(signalROI,2);%temporal points changed!
                    BL=mean(meanRoi(1:preF-1));
                    BL=repmat(BL,size(meanRoi));
                    app.ROI(k).signal=(meanRoi-BL)./BL*100;
                    %plot
                    prof(k)=figure('Name', ['ROI ' num2str(k) ' ' app.name]);
                    figure(prof(k));
                    t_scale=-preF*app.framePeriod:app.framePeriod:postF*app.framePeriod;
                    plot(t_scale, app.ROI(k).signal);

                    title(['ROI ' num2str(k)])
                    xlabel('t [s]')
                    ylabel('dF/F0 [%]')
                    yl=ylim;
                    if app.mod==1||app.mod==3
                            pos=[0, yl(1)+0.02*yl(2), app.durationStim, yl(2)-yl(1)];
                            rectangle('Position', pos, 'EdgeColor', 'r')
                    end
                    if app.mod==2||app.mod==3
                          line([0,0],ylim,'Color','r')
                    end
                end
                correlation(app)
            end
            
        end

        % Button pushed function: Plot2DdFButton
        function Plot2DdFButtonPushed(app, event)
            dFstack(app)
        end

        % Value changed function: F0averagedbetweensEditField
        function F0averagedbetweensEditFieldValueChanged(app, event)
            app.BT = app.F0averagedbetweensEditField.Value;
            app.startaverg.Limits = [0 app.BT];
            plotProfile(app)
        end

        % Value changed function: startaverg
        function startavergValueChanged(app, event)
            app.BTstart = app.startaverg.Value;
            plotProfile(app)
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            
            value = app.Button_3.Value;
            if value==0
                app.PrestimulussEditField.Enable='on';
                app.StimulussEditField.Enable='on';
                app.InterstimulisEditField.Enable='on';
                app.RepetitionsEditField.Enable='on';
                updateActFr(app)
            else
                app.PrestimulussEditField.Enable='off';
                app.StimulussEditField.Enable='off';
                app.InterstimulisEditField.Enable='off';
                app.RepetitionsEditField.Enable='off';
                app.actTimes=[];
                app.delay=[];
                app.actStartFr=[];
                app.duratFr=[];
            end
            if value==0&&app.Button_2.Value==0
                app.mod=3;
            elseif value==0&&app.Button_2.Value==1
                app.mod=1;
            elseif value==1&&app.Button_2.Value==0
                app.mod=2;
            elseif value==1&&app.Button_2.Value==1
                app.mod=0;
            end
            plotProfile(app)
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)

            value = app.Button_2.Value;
            if value==0&&app.Button_3.Value==0
                app.mod=3;
            elseif value==0&&app.Button_3.Value==1
                app.mod=2;
            elseif value==1&&app.Button_3.Value==0
                app.mod=1;
            elseif value==1&&app.Button_3.Value==1
                app.mod=0;
            end
            if value==0
                app.ListactivationframesEditField.Enable='on';
                app.EvaluateframesautoButton.Enable='on';
                app.withthreasholdEditField.Enable='on';
                app.CutButton.Enable='on';
                removal(app);
            else
                app.ListactivationframesEditField.Enable='off';
                app.EvaluateframesautoButton.Enable='off';
                app.withthreasholdEditField.Enable='off';
                app.CutButton.Enable='off';
                app.ListactivationframesEditField.Value="";
                app.actTimes=[];
                app.delay=[];
                app.actStartFr=[];
                app.duratFr=[];
                app.currentData=double(app.CallingApp.data);
                app.currentThreshData=double(app.CallingApp.threshData);
                app.newnumFrames=app.CallingApp.frames;
                plotProfile(app)
            end
        end

        % Button pushed function: EvaluateframesautoButton
        function EvaluateframesautoButtonPushed(app, event)
            app.actFr=[];
            ref= mean(mean(mean(app.currentThreshData(:,:,1:15))))+ mean(mean(mean(app.currentThreshData(:,:,1:15))))*app.withthreasholdEditField.Value/100;
            for i=1:app.newnumFrames
                if mean(mean(app.currentThreshData(:,:,i)))>ref
                    app.actFr=[app.actFr i];
                end
            end
            app.ListactivationframesEditField.Value=num2str(app.actFr);
            ListactivationframesEditFieldValueChanged(app);
        end

        % Value changed function: ListactivationframesEditField
        function ListactivationframesEditFieldValueChanged(app, event)

            value = app.ListactivationframesEditField.Value;
            if isempty(value)
                app.duratFr=[];
                app.actStartFr=[];
            else
                app.actFr=(str2num(value));
                app.actStartFr=zeros();
                app.duratFr=zeros();
                app.actStartFr(1)=app.actFr(1);
                app.duratFr(1)=1;
                k=1;
                c=1;
                for i=2:size(app.actFr,2)
                    if app.actFr(i)==app.actFr(i-1)+1
                        c=c+1;
                        app.duratFr(k)=c;
                        if (i~=size(app.actFr,2))&&(app.actFr(i+1)~=app.actFr(i)+1)
                            c=1;
                        end
                    else
                        k=k+1;
                        app.duratFr(k)=c;
                        app.actStartFr(k)=app.actFr(i);
                        c=1;
                    end
                end
            end
        end

        % Button pushed function: ComparestimuliButton
        function ComparestimuliButtonPushed(app, event)
            
            if app.mod==0
                disp('You need to select a stimulus before!')
            else
                prompt = {'Enter average time [s]:'};
                dlgtitle = 'Average timing';
                definput={'0.5'};
                inp=cell2mat(inputdlg(prompt,dlgtitle,[1 35],definput));
                app.tMean = str2double(inp);
                for i=1:size(app.actStartFr,2)
                    preFr(i,1)=app.actStartFr(i)-1-uint16(app.tMean/app.framePeriod);
                    preFr(i,2)=app.actStartFr(i)-1;
                    postFr(i,1)=app.actStartFr(i)+app.duratFr(i);
                    postFr(i,2)=app.actStartFr(i)+app.duratFr(i)+uint16(app.tMean/app.framePeriod);
                    %                     if app.VisualizestimuliseparatedCheckBox.Value==1
                    %                         compareFr(app, [preFr(i,1) preFr(i,2)], [postFr(i,1) postFr(i,2)],i);
                    %                     end
                end
                subtrFrames(app, preFr, postFr)
            end
        end

        % Button pushed function: CutButton
        function CutButtonPushed(app, event)
            removal(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all;
            clc;
            delete(app)
            
        end

        % Button pushed function: CrosscorrelationButton
        function CrosscorrelationButtonPushed(app, event)
            markp=figure(1000);
            markp.Position=[40 620 560 420];
            set(markp,'Name',['Mark point ref - ' app.name]);
            imagesc(app.imRef(:,:,1:3))
            title(['Mark point reference']);
            fig=figure(1);
            imagesc(sum(app.resizeRef,3));
            title('Select reference point:')
            [xp,yp]=getpts(fig);
            app.xp=round(yp);
            app.yp=round(xp);
            close(fig)
            %data report modality
            dlgTitle    = 'Stimuli';
            dlgQuestion = 'How do you want to analize your responces?';
            inp = questdlg(dlgQuestion,dlgTitle,'Together','Separate','Together');
            if inp=='Together'
                for i=1:50
                    for j=1:50
                        xx=squeeze(app.resizeRef(i,j,:));
                        yy=squeeze(app.resizeRef(app.xp,app.yp,:));
                        [R,P] = corrcoef(xx,yy);
                        [C,L]=xcorr(xx,yy);%,'normalized');
                        corrmat(i,j)=R(1,2);
                        probmat(i,j)=P(1,2);
                        [crosscorr(i,j), ind]=max(C);
                        lags(i,j)=L(ind).*app.framePeriod;
                    end
                end
                %Plot
                figure('Name',['Correlations matrix'])
                imagesc(corrmat(:,:))
                caxis([-1 1])
                colormap jet
                colorbar
                title(['Correlation matrix between points'])
 
                figure('Name','Cross-correlation matrices')
                suptitle('Cross-correlation between points')
                subplot(1,2,1)
                imagesc(crosscorr(:,:))
                caxis([0 1])
                colormap jet
                colorbar
                title('Maximum cross-correlation')
                subplot(1,2,2)
                imagesc(lags(:,:))
                colormap jet
                colorbar
                title('Lags @maximum correlation')
            else
                correlationCutTot(app)   
            end
        end

        % Button pushed function: wcoherenceButton
        function wcoherenceButtonPushed(app, event)
            %draw ROIs tool
            clc;
            app.lc={};
            app.pk={};
            keep = questdlg('Would you like to keep previous ROIs?', 'ROIs selection', 'Ok', 'No','Ok');
            if keep=='No'
                markp=figure(1000);
                markp.Position=[40 620 560 420];
                set(markp,'Name',['Mark point ref - ' app.name]);
                imagesc(app.imRef(:,:,1:3))
                title(['Mark point reference']);
                figure(1);
                imagesc(sum(app.currentThreshData,3));
                title('Select ROI:')
                color = lines;
                hold on
                ButtonHandle = uicontrol('Style', 'PushButton','String', 'End','Callback', 'delete(gcf)');
                counter=0;
                app.ROI=[];
                while 1
                    h = drawfreehand;
                    counter=counter+1;
                    if  ~ishandle(ButtonHandle)
                        break
                    end
                    app.ROI(counter).mask = createMask(h);
                    app.ROI(counter).border = cell2mat(bwboundaries(app.ROI(counter).mask));
                    app.ROI(counter).center = mean(app.ROI(counter).border);
                    [row,col]=find(app.ROI(counter).border==1);
                    app.ROI(counter).area=[col,row];
                    plot(col,row)
                end
            end
            %data report modality
            dlgTitle    = 'Time window';
            dlgQuestion = {'Start time:','End time'};
            inp = inputdlg(dlgQuestion,dlgTitle);
            start_Fr=uint32(str2num(inp{1})/app.framePeriod);
            end_Fr=uint32(str2num(inp{2})/app.framePeriod);
            for k=1:length(app.ROI)
                roimask=repmat(app.ROI(k).mask,[1 1 size(app.currentThreshData,3)]);
                accept=app.currentThreshData.*roimask;
                sumAcc=squeeze(sum(sum(accept)));
                pixelMask=sum(sum(app.ROI(k).mask));
                for j=1:size(accept,3)
                    signal(j)=sumAcc(j)/pixelMask;
                end
                BL1=start_Fr-20;
                if BL1<=0
                    BL1=1;
                end
                BL2=start_Fr-1;
                BLsignal=mean(signal(BL1:BL2));
                app.ROI(k).signal=(signal-BLsignal)/BLsignal*100;
                %app.ROI(k).filteredsignal = lowpass(app.ROI(k).signal, 0.4);
                app.ROI(k).filteredsignal = normalize(lowpass(app.ROI(k).signal, 0.4));
            end
            close all;
            froi=figure(2000);
            set(froi,'Name',['ROIs position ' app.name]);
            froi.Position=[40 80 560 420];
            imagesc(sum(app.currentThreshData,3));
            title('ROIs')
            hold on
            for k = 1:length(app.ROI)
                figure(froi)
                plot(app.ROI(k).border(:,2),app.ROI(k).border(:,1),'k','Linewidth',1)
                text(app.ROI(k).center(2), app.ROI(k).center(1),sprintf(string(k)),'HorizontalAlignment', 'center','VerticalAlignment', 'middle');
            end
            max_l=0;
            for j = 2:length(app.ROI)
                figure(3000+j)
                subplot(2,1,1)
                t_scale=0:app.framePeriod:double((end_Fr-start_Fr)*app.framePeriod);
%                 y_1=app.ROI(1).signal(start_Fr:end_Fr);
%                 y_2=app.ROI(j).signal(start_Fr:end_Fr);
                y_1=app.ROI(1).filteredsignal(start_Fr:end_Fr);
                y_2=app.ROI(j).filteredsignal(start_Fr:end_Fr);
                [app.pk{1}, app.lc{1}] = findpeaks(y_1,1/app.framePeriod);
                if length(app.lc{1})>max_l
                    max_l=length(app.lc{1});
                end
                [app.pk{j}, app.lc{j}] = findpeaks(y_2,1/app.framePeriod);
                if length(app.lc{j})>max_l
                    max_l=length(app.lc{j});
                end
                minlen = min(length(t_scale), length(y_1));
                plot(t_scale(1:minlen), y_1(1:minlen))
                legendInfo{1}='ROI 1';
                hold on;
                plot(t_scale(1:minlen), y_2(1:minlen))
                legendInfo{2}=['ROI ' num2str(j)];
                plot(app.lc{1},app.pk{1},'x')
                plot(app.lc{j},app.pk{j},'x')
                xlabel('t [s]')
                %ylabel('dF/F0 [%]')
                ylabel('Normalized dF/F0')
                legend(legendInfo)
                subplot(2,1,2)
                wcoherence(y_1,y_2,1/app.framePeriod)
            end
            time_mtx=NaN(length(app.ROI),max_l);
            for k = 1:length(app.ROI)
                if length(app.lc{k}<max_l)
                    app.lc{k}(end+1:max_l)=NaN;
                end
                time_mtx(k,:)=app.lc{k};
            end
            [M,I] = sort(time_mtx);
            figure(4000)
            subplot(1,2,1)
            h=imagesc(M);
            set(h,'alphadata',~isnan(M))
            xlabel('peak#')
            ylabel('ROI')
            title('Peaks times')
            colorbar
            subplot(1,2,2)
            h=imagesc(I);
            set(h,'alphadata',~isnan(I))
            xlabel('peak#')
            ylabel('ROI')
            title('Peaks ranks')
            colorbar
                figure('Name','Cross-correlation matrices')
                for j = 1:length(app.ROI)
                        %[C,L]=xcorr(app.ROI(1).filteredsignal(start_Fr:end_Fr),app.ROI(j).filteredsignal(start_Fr:end_Fr),'normalized');
                        [C,L]=xcorr(app.ROI(1).signal(start_Fr:end_Fr),app.ROI(j).signal(start_Fr:end_Fr),'normalized');
                        [R,P] = corrcoef(squeeze(app.ROI(1).signal(start_Fr:end_Fr)),squeeze(app.ROI(j).signal(start_Fr:end_Fr)));
                        corrmat(1,j)=R(1,2);
                        [crosscorr(1,j), ind]=max(C);
                        lags(1,j)=L(ind).*app.framePeriod;
                        subplot(3,1,1)
                        plot(L*app.framePeriod,C)
                        legend
                        hold on;
                end
                xlabel('lags [s]')
                ylabel('Correlation coefficient')
                title('Cross-correlation with ROI 1')
                subplot(3,1,2)
                imagesc(lags(:,:))
                xlabel('ROI #')
                colormap jet
                colorbar
                title('Lags @maximum correlation')
                
                subplot(3,1,3)
                imagesc(corrmat(:,:))
                xlabel('ROI #')
                colormap jet
                colorbar
                caxis([-1 1]);
                title('Static correlation')
                for i = 1:length(app.ROI)
                    for j = 1:length(app.ROI)
                        %[C,L]=xcorr(app.ROI(1).filteredsignal(start_Fr:end_Fr),app.ROI(j).filteredsignal(start_Fr:end_Fr),'normalized');
                        %[C,L]=xcorr(app.ROI(i).signal(start_Fr:end_Fr),app.ROI(j).signal(start_Fr:end_Fr),'normalized');
                        [R,P] = corrcoef(squeeze(app.ROI(i).signal(start_Fr:end_Fr)),squeeze(app.ROI(j).signal(start_Fr:end_Fr)));
                        corrmat(i,j)=R(1,2);
                        %[crosscorr(1,j), ind]=max(C);
                        %lags(1,j)=L(ind).*app.framePeriod;
                    end
                end
                figure('Name','Static correlation matrix')
                imagesc(corrmat(:,:))
                xlabel('ROI #')
                ylabel('ROI #')
                colormap jet
                colorbar
                caxis([-1 1]);
                title(['Correlation between ROIs for signal at ' num2str(start_Fr*app.framePeriod) 's-' num2str(end_Fr*app.framePeriod) 's'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ROIsanalysisButton = uibutton(app.UIFigure, 'push');
            app.ROIsanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ROIsanalysisButtonPushed, true);
            app.ROIsanalysisButton.Text = 'ROIs analysis';
            app.OdorstimulusparametersPanel = uipanel(app.UIFigure);
            app.OdorstimulusparametersPanel.Title = 'Odor stimulus parameters';
            app.PrestimulussEditField_2Label = uilabel(app.OdorstimulusparametersPanel);
            app.PrestimulussEditField_2Label.Text = 'Pre-stimulus [s]';
            app.PrestimulussEditField = uieditfield(app.OdorstimulusparametersPanel, 'numeric');
            app.PrestimulussEditField.Limits = [0 Inf];
            app.PrestimulussEditField.ValueChangedFcn = createCallbackFcn(app, @PrestimulussEditFieldValueChanged, true);
            app.PrestimulussEditField.Enable = 'off';
            app.PrestimulussEditField.Value = 30;
            app.StimulussEditFieldLabel = uilabel(app.OdorstimulusparametersPanel);
            app.StimulussEditFieldLabel.Text = 'Stimulus [s]';
            app.StimulussEditField = uieditfield(app.OdorstimulusparametersPanel, 'numeric');
            app.StimulussEditField.Limits = [0 Inf];
            app.StimulussEditField.ValueChangedFcn = createCallbackFcn(app, @StimulussEditFieldValueChanged, true);
            app.StimulussEditField.Enable = 'off';
            app.StimulussEditField.Value = 3;
            app.InterstimulisEditFieldLabel = uilabel(app.OdorstimulusparametersPanel);
            app.InterstimulisEditFieldLabel.Text = 'Inter-stimuli [s]';
            app.InterstimulisEditField = uieditfield(app.OdorstimulusparametersPanel, 'numeric');
            app.InterstimulisEditField.Limits = [0 Inf];
            app.InterstimulisEditField.ValueChangedFcn = createCallbackFcn(app, @InterstimulisEditFieldValueChanged, true);
            app.InterstimulisEditField.Enable = 'off';
            app.InterstimulisEditField.Value = 20;
            app.RepetitionsEditFieldLabel = uilabel(app.OdorstimulusparametersPanel);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.RepetitionsEditField = uieditfield(app.OdorstimulusparametersPanel, 'numeric');
            app.RepetitionsEditField.Limits = [0 Inf];
            app.RepetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @RepetitionsEditFieldValueChanged, true);
            app.RepetitionsEditField.Enable = 'off';
            app.RepetitionsEditField.Value = 3;
            app.Button_3 = uibutton(app.OdorstimulusparametersPanel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '';
            app.Button_3.Value = true;
            app.BaselinePanel = uipanel(app.UIFigure);
            app.BaselinePanel.Title = 'Baseline';
            app.startaverg = uieditfield(app.BaselinePanel, 'numeric');
            app.startaverg.ValueChangedFcn = createCallbackFcn(app, @startavergValueChanged, true);
            app.F0averagedbetweensEditFieldLabel = uilabel(app.BaselinePanel);
            app.F0averagedbetweensEditFieldLabel.Text = 'F0 averaged between [s]';
            app.F0averagedbetweensEditField = uieditfield(app.BaselinePanel, 'numeric');
            app.F0averagedbetweensEditField.Limits = [0 Inf];
            app.F0averagedbetweensEditField.ValueChangedFcn = createCallbackFcn(app, @F0averagedbetweensEditFieldValueChanged, true);
            app.F0averagedbetweensEditField.Value = 1;
            app.TextArea = uitextarea(app.BaselinePanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The 1D F0 is calculated averaging over all pixels of a frame and averaging over time interval specified. This value is subtracted to every frame pixels average for the dF computation.'; ''; 'The 2D F0 (figure) is calculated averaging over time in the interval specified, pixel by pixel. the frames are subtracted pixel by pixel for the 2D dF computation.'};
            app.DF0TextAreaLabel = uilabel(app.BaselinePanel);
            app.DF0TextAreaLabel.Text = '1D F0';
            app.DF0TextArea = uitextarea(app.BaselinePanel);
            app.DF0TextArea.Editable = 'off';
            app.F0fig = uiaxes(app.BaselinePanel);
            title(app.F0fig, '2D F0')
            app.Plot2DdFButton = uibutton(app.UIFigure, 'push');
            app.Plot2DdFButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2DdFButtonPushed, true);
            app.Plot2DdFButton.Text = 'Plot 2D dF';
            app.LightstimulusparametersPanel = uipanel(app.UIFigure);
            app.LightstimulusparametersPanel.Title = 'Light stimulus parameters';
            app.ListactivationframesseparetedbyspaceEditFieldLabel = uilabel(app.LightstimulusparametersPanel);
            app.ListactivationframesseparetedbyspaceEditFieldLabel.Text = 'List activation frames (separeted by space)';
            app.ListactivationframesEditField = uieditfield(app.LightstimulusparametersPanel, 'text');
            app.ListactivationframesEditField.ValueChangedFcn = createCallbackFcn(app, @ListactivationframesEditFieldValueChanged, true);
            app.ListactivationframesEditField.Enable = 'off';
            app.EvaluateframesautoButton = uibutton(app.LightstimulusparametersPanel, 'push');
            app.EvaluateframesautoButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateframesautoButtonPushed, true);
            app.EvaluateframesautoButton.Enable = 'off';
            app.EvaluateframesautoButton.Text = 'Evaluate frames auto';
            app.withthreasholdEditFieldLabel = uilabel(app.LightstimulusparametersPanel);
            app.withthreasholdEditFieldLabel.Text = 'with threashold';
            app.withthreasholdEditField = uieditfield(app.LightstimulusparametersPanel, 'numeric');
            app.withthreasholdEditField.Limits = [1 Inf];
            app.withthreasholdEditField.Enable = 'off';
            app.withthreasholdEditField.Value = 30;
            app.Button_2 = uibutton(app.LightstimulusparametersPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '';
            app.Button_2.Value = true;
            app.CutButton = uibutton(app.LightstimulusparametersPanel, 'push');
            app.CutButton.ButtonPushedFcn = createCallbackFcn(app, @CutButtonPushed, true);
            app.CutButton.Enable = 'off';
            app.CutButton.Text = 'Cut';
            app.ComparestimuliButton = uibutton(app.UIFigure, 'push');
            app.ComparestimuliButton.ButtonPushedFcn = createCallbackFcn(app, @ComparestimuliButtonPushed, true);
            app.ComparestimuliButton.Text = 'Compare stimuli';
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Text = 'Mean';
            app.RMSLabel = uilabel(app.UIFigure);
            app.RMSLabel.Text = 'RMS';
            app.MeanLabel_2 = uilabel(app.UIFigure);
            app.MeanLabel_2.Text = 'Mean';
            app.RMSLabel_2 = uilabel(app.UIFigure);
            app.RMSLabel_2.Text = 'RMS';
            app.StdLabel = uilabel(app.UIFigure);
            app.StdLabel.Text = 'Std';
            app.StdLabel_2 = uilabel(app.UIFigure);
            app.StdLabel_2.Text = 'Std';
            app.CrosscorrelationButton = uibutton(app.UIFigure, 'push');
            app.CrosscorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CrosscorrelationButtonPushed, true);
            app.CrosscorrelationButton.Text = 'Cross-correlation';
            app.wcoherenceButton = uibutton(app.UIFigure, 'push');
            app.wcoherenceButton.ButtonPushedFcn = createCallbackFcn(app, @wcoherenceButtonPushed, true);
            app.wcoherenceButton.Text = 'wcoherence';
            app.Iprofile = uiaxes(app.UIFigure);
            title(app.Iprofile, 'Thresholded intesity profile')
            app.Fcut = uiaxes(app.UIFigure);
            title(app.Fcut, 'Thresholded dF profile')
            xlabel(app.Fcut, 't [s]')
            ylabel(app.Fcut, 'dF/F0 [%]')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Power spectrum')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'f [Hz]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = intensityAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end