classdef mainTS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TimeSeriesAnalysisMain       matlab.ui.Figure
        FrameSliderLabel             matlab.ui.control.Label
        FrameSlider                  matlab.ui.control.Slider
        ThreasholdlowSliderLabel     matlab.ui.control.Label
        ThreasholdlowSlider          matlab.ui.control.Slider
        PlotprofileButton            matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        Frame                        matlab.ui.control.NumericEditField
        PlaystackButton              matlab.ui.control.Button
        frT                          matlab.ui.control.Label
        SavestackasvideoButton       matlab.ui.control.Button
        next                         matlab.ui.control.Button
        prev                         matlab.ui.control.Button
        OpenfilePanel                matlab.ui.container.Panel
        ReferencepathEditFieldLabel  matlab.ui.control.Label
        ReferencepathEditField       matlab.ui.control.EditField
        DateEditFieldLabel           matlab.ui.control.Label
        DateEditField                matlab.ui.control.EditField
        OpenfileButton               matlab.ui.control.Button
        ChannelButtonGroup           matlab.ui.container.ButtonGroup
        Ch1Button                    matlab.ui.control.RadioButton
        Ch2Button                    matlab.ui.control.RadioButton
        FilteringtoolsButton         matlab.ui.control.Button
        ThreasholdhighSliderLabel    matlab.ui.control.Label
        ThreasholdhighSlider         matlab.ui.control.Slider
        MarkpointreferenceButton     matlab.ui.control.Button
        DataImg                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        mainPath
        date
        filename
        fullpath
        data
        threshData
        frames
        currentFr
        framePeriod
        pixel
        preMean
        postMean
        frStartPre
        frStartPost
        frStopPre
        frStopPost
        duratFr
        firstFr
        actFr
        imRef
        name
    end

    properties (Access = private)
        intensityApp % Description
    end

    methods (Access = private)
    
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReferencepathEditField
        function ReferencepathEditFieldValueChanged(app, event)
            app.mainPath = app.ReferencepathEditField.Value;    
        end

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)

            app.mainPath = app.ReferencepathEditField.Value;
            app.date = app.DateEditField.Value;
            app.currentFr = 1;
            app.FrameSlider.Value=1;
            app.Frame.Value=1;
            app.ThreasholdlowSlider.Value=0;
            app.ThreasholdhighSlider.Value=3000;
            %app.DataImg.cla();
            app.frT.Text='Not ready';
            
            clc;
            [file, app.fullpath]=uigetfile([app.mainPath '\' app.date '\*.xml']);
            figure(app.TimeSeriesAnalysisMain)
            if app.Ch1Button.Value==1
                app.filename=[app.fullpath,'*Ch1*'];
            else
                app.filename=[app.fullpath,'*Ch2*'];
            end
            tifarray=dir([app.filename,'.tif']);
            app.frames=length(tifarray);
            if  app.frames ~= 0
                app.FrameSlider.Limits = [1 app.frames];
                app.Frame.Limits = [1 app.frames];
                app.TimeSeriesAnalysisMain.Name = ['Time Series Analysis - Main - ' file];
                app.name=file;
                
                % Read in metadata from xml file
                app.fullpath = regexprep(app.fullpath, '\', '/');
                text = fileread([app.fullpath '/' file]);
                ref = dir(fullfile([app.fullpath, 'References'],'*8bit*'));
                nome=ref.name;
                app.imRef=imread([app.fullpath, 'References\', nome]); 
                
                % Micron per pixel and frame period
                app.pixel = str2double(regexp(text,'PVStateValue key="pixelsPerLine".*?value="(\d*)"', 'tokens','once'));
                metadata.dx_um = mode(cellfun(@(x) str2double(x{1}), regexp(text, '<PVStateValue key="micronsPerPixel".*?value="([-\d\.]*?)"', 'tokens'))); 
                metadata.dy_um = mode(cellfun(@(x) str2double(x{1}), regexp(text, '<PVStateValue key="micronsPerPixel".*?value="([-\d\.]*?)".*?value="([-\d\.]*?)"', 'tokens'))); 
                frameP = mode(cellfun(@(x) str2double(x{1}), regexp(text, '<PVStateValue key="framePeriod".*?value="([-\d\.]*?)".*?value="([-\d\.]*?)"', 'tokens')));
                rasterFrame = mode(cellfun(@(x) str2double(x{1}), regexp(text, '<PVStateValue key="rastersPerFrame".*?value="([-\d\.]*?)"', 'tokens')));
                app.framePeriod=frameP*rasterFrame;
                
                xlim(app.DataImg, [0 app.pixel]);
                ylim(app.DataImg, [0 app.pixel]);
                
                %% Read image data
                app.data=[];
                app.threshData=[];
                path = app.fullpath(1:end-1);
                if app.Ch1Button.Value==1
                    file3D=[path 'Ch1.tif'];
                else
                    file3D=[path 'Ch2.tif'];
                end
                if isfile(file3D)
                    tstart=tic;%start timer
                    % space allocatipon
                    infoimage=imfinfo(file3D);     
                    width=infoimage(1).Width;
                    heigth=infoimage(1).Height;
                    numberimages=length(infoimage);
                    tseries=zeros(heigth,width,numberimages,'uint16');
                    % load tif stack
                    tiflink = Tiff(file3D, 'r');
                    for i=1:numberimages
                       tiflink.setDirectory(i);
                       tseries(:,:,i)=tiflink.read();
                    end
                    tiflink.close();
                    tend=toc(tstart);
                    app.data=tseries;
                else
                    disp('You have not previously created an optimized 3D-tif file. This import procedure will take more time!')
                    for i=1:app.frames
                        app.data(:,:,i)=imread([app.fullpath,tifarray(i).name]);
                    end
                end
                app.threshData=app.data;
                
                %plot
                imagesc(app.DataImg, app.threshData(:,:,1))
                title(app.DataImg,'Data')
                xlabel(app.DataImg,'')
                ylabel(app.DataImg,'')
                app.frT.Text=['One frame is ' num2str(app.framePeriod) 's'];
            else
                disp('You have probably selected the wrong channel, no file found!')
            end
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.currentFr = uint16(app.FrameSlider.Value);
            app.Frame.Value=double(app.currentFr);
            imagesc(app.DataImg, app.threshData(:,:,app.currentFr))
        end

        % Value changed function: DateEditField
        function DateEditFieldValueChanged(app, event)
            app.date = app.DateEditField.Value;
            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            app.currentFr = uint16(event.Value);
            app.Frame.Value=double(app.currentFr);
            imagesc(app.DataImg, app.threshData(:,:,app.currentFr))
        end

        % Value changing function: ThreasholdlowSlider
        function ThreasholdlowSliderValueChanging(app, event)
            thresh = event.Value;
            app.ThreasholdhighSlider.Value=3000;
            thrpixel=uint16(app.data>thresh);
            app.threshData=uint16(app.data).*thrpixel;
            imagesc(app.DataImg, app.threshData(:,:,app.currentFr))
        end

        % Value changed function: Frame
        function FrameValueChanged(app, event)
            app.FrameSlider.Value = double(app.Frame.Value);
            app.currentFr=uint16(app.FrameSlider.Value);
            imagesc(app.DataImg, app.threshData(:,:,app.currentFr))
        end

        % Button pushed function: PlaystackButton
        function PlaystackButtonPushed(app, event)
            if app.PlaystackButton.Text=='Play stack'
                app.PlaystackButton.Text='Stop stack';
                while app.PlaystackButton.Text == 'Stop stack'
                    if app.currentFr==app.frames
                        app.currentFr=1;
                    else
                        app.currentFr=app.currentFr+1;
                    end
                    app.FrameSlider.Value = double(app.currentFr);
                    app.Frame.Value = double(app.currentFr);
                    imagesc(app.DataImg, app.threshData(:,:,app.currentFr))
                    pause(0.02)
                end
            else
                app.PlaystackButton.Text='Play stack';
            end
        end

        % Button pushed function: SavestackasvideoButton
        function SavestackasvideoButtonPushed(app, event)
            selpath = uigetdir('C:\Users\mirko.zanon\Desktop');
            prompt = {'Video name:'};
            dlgtitle = 'Video name';
            nm=inputdlg(prompt,dlgtitle);
            nameV=cell2mat(nm);
            v = VideoWriter([selpath '\' nameV '.avi']);
            prompt = {'Frame rate:'};
            dlgtitle = 'Frame rate';
            fR=inputdlg(prompt,dlgtitle);
            frameR=str2num(cell2mat(fR));
            v.FrameRate=frameR;
            open(v);
            for i = 1:app.frames
                I=mat2gray(app.threshData(:,:,i));
                imwrite(I,'frame.jpg');
                fr=imread('frame.jpg');
                writeVideo(v,fr);
            end
            delete('frame.jpg')
            close(v);
        end

        % Button pushed function: next
        function nextButtonPushed(app, event)
            if app.currentFr==app.frames
                app.currentFr=1;
            else
                app.currentFr = app.currentFr+1;
            end
            app.Frame.Value=double(app.currentFr);
            app.FrameSlider.Value = double(app.currentFr);
            imagesc(app.DataImg, app.threshData(:,:,app.currentFr))
        end

        % Button pushed function: prev
        function prevButtonPushed(app, event)
            if app.currentFr==1
                app.currentFr=app.frames;
            else
                app.currentFr = app.currentFr-1;
            end
            app.Frame.Value=double(app.currentFr);
            app.FrameSlider.Value = double(app.currentFr);
            imagesc(app.DataImg, app.threshData(:,:,app.currentFr))
        end

        % Button pushed function: PlotprofileButton
        function PlotprofileButtonPushed(app, event)
            intensityAnalysis(app)
        end

        % Button pushed function: FilteringtoolsButton
        function FilteringtoolsButtonPushed(app, event)
            filterTool(app)
        end

        % Value changing function: ThreasholdhighSlider
        function ThreasholdhighSliderValueChanging(app, event)

            thresh = event.Value;
            app.ThreasholdlowSlider.Value=0;
            thrpixel=uint16(app.data<thresh);
            app.threshData=uint16(app.data).*thrpixel;
            imagesc(app.DataImg, app.threshData(:,:,app.currentFr))
        end

        % Button pushed function: MarkpointreferenceButton
        function MarkpointreferenceButtonPushed(app, event)

            figure(1000)
            imagesc(app.imRef(:,:,1:3))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TimeSeriesAnalysisMain = uifigure('Visible', 'off');
            app.TimeSeriesAnalysisMain.Name = 'UI Figure';
            app.FrameSliderLabel = uilabel(app.TimeSeriesAnalysisMain);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.TimeSeriesAnalysisMain);
            app.FrameSlider.Limits = [1 30000];
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.MajorTickLabels = {};
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameSlider.Value = 1;
            app.ThreasholdlowSliderLabel = uilabel(app.TimeSeriesAnalysisMain);
            app.ThreasholdlowSliderLabel.Text = 'Threashold low';
            app.ThreasholdlowSlider = uislider(app.TimeSeriesAnalysisMain);
            app.ThreasholdlowSlider.Limits = [0 3000];
            app.ThreasholdlowSlider.ValueChangingFcn = createCallbackFcn(app, @ThreasholdlowSliderValueChanging, true);
            app.PlotprofileButton = uibutton(app.TimeSeriesAnalysisMain, 'push');
            app.PlotprofileButton.ButtonPushedFcn = createCallbackFcn(app, @PlotprofileButtonPushed, true);
            app.PlotprofileButton.Text = 'Plot profile';
            app.Label = uilabel(app.TimeSeriesAnalysisMain);
            app.Label.Text = '';
            app.Frame = uieditfield(app.TimeSeriesAnalysisMain, 'numeric');
            app.Frame.Limits = [1 Inf];
            app.Frame.ValueChangedFcn = createCallbackFcn(app, @FrameValueChanged, true);
            app.Frame.Value = 1;
            app.PlaystackButton = uibutton(app.TimeSeriesAnalysisMain, 'push');
            app.PlaystackButton.ButtonPushedFcn = createCallbackFcn(app, @PlaystackButtonPushed, true);
            app.PlaystackButton.Text = 'Play stack';
            app.frT = uilabel(app.TimeSeriesAnalysisMain);
            app.frT.Text = 'Not ready';
            app.SavestackasvideoButton = uibutton(app.TimeSeriesAnalysisMain, 'push');
            app.SavestackasvideoButton.ButtonPushedFcn = createCallbackFcn(app, @SavestackasvideoButtonPushed, true);
            app.SavestackasvideoButton.Text = 'Save stack as video';
            app.next = uibutton(app.TimeSeriesAnalysisMain, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.next.Text = '>>';
            app.prev = uibutton(app.TimeSeriesAnalysisMain, 'push');
            app.prev.ButtonPushedFcn = createCallbackFcn(app, @prevButtonPushed, true);
            app.prev.Text = '<<';
            app.OpenfilePanel = uipanel(app.TimeSeriesAnalysisMain);
            app.OpenfilePanel.Title = 'Open file';
            app.ReferencepathEditFieldLabel = uilabel(app.OpenfilePanel);
            app.ReferencepathEditFieldLabel.Text = 'Reference path';
            app.ReferencepathEditField = uieditfield(app.OpenfilePanel, 'text');
            app.ReferencepathEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencepathEditFieldValueChanged, true);
            app.ReferencepathEditField.Value = '\\cimec-storage\albhaa\haaalb001a1p\Data\Mirko Zanon Data\Raw Data\Drosophilae';
            app.DateEditFieldLabel = uilabel(app.OpenfilePanel);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.OpenfilePanel, 'text');
            app.DateEditField.ValueChangedFcn = createCallbackFcn(app, @DateEditFieldValueChanged, true);
            app.OpenfileButton = uibutton(app.OpenfilePanel, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Text = 'Open file';
            app.ChannelButtonGroup = uibuttongroup(app.OpenfilePanel);
            app.ChannelButtonGroup.Title = 'Channel';
            app.Ch1Button = uiradiobutton(app.ChannelButtonGroup);
            app.Ch1Button.Text = 'Ch1';
            app.Ch2Button = uiradiobutton(app.ChannelButtonGroup);
            app.Ch2Button.Text = 'Ch2';
            app.Ch2Button.Value = true;
            app.FilteringtoolsButton = uibutton(app.TimeSeriesAnalysisMain, 'push');
            app.FilteringtoolsButton.ButtonPushedFcn = createCallbackFcn(app, @FilteringtoolsButtonPushed, true);
            app.FilteringtoolsButton.Text = 'Filtering tools';
            app.ThreasholdhighSliderLabel = uilabel(app.TimeSeriesAnalysisMain);
            app.ThreasholdhighSliderLabel.Visible = 'off';
            app.ThreasholdhighSliderLabel.Text = 'Threashold high';
            app.ThreasholdhighSlider = uislider(app.TimeSeriesAnalysisMain);
            app.ThreasholdhighSlider.Limits = [0 3000];
            app.ThreasholdhighSlider.ValueChangingFcn = createCallbackFcn(app, @ThreasholdhighSliderValueChanging, true);
            app.ThreasholdhighSlider.Visible = 'off';
            app.ThreasholdhighSlider.Value = 3000;
            app.MarkpointreferenceButton = uibutton(app.TimeSeriesAnalysisMain, 'push');
            app.MarkpointreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @MarkpointreferenceButtonPushed, true);
            app.MarkpointreferenceButton.Text = 'Mark point reference';
            app.DataImg = uiaxes(app.TimeSeriesAnalysisMain);
            title(app.DataImg, 'Open file')
            % Show the figure after all components are created
            app.TimeSeriesAnalysisMain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainTS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TimeSeriesAnalysisMain)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TimeSeriesAnalysisMain)
        end
    end
end