classdef activationProfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        horz      matlab.ui.control.UIAxes
        vert      matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
        AveragetimeforframecomparisonsEditFieldLabel  matlab.ui.control.Label
        AveragetimeforframecomparisonsEditField  matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp % Description
    end

    properties (Access = public)
        frame % Description
        mOverFr
    end
    
    methods (Access = public)
    
        function plotSlidingROIx(app)
             xmin=1;
             ymin=uint16(app.CallingApp.posROI(2));
             xmax=uint16(app.CallingApp.posROI(3));
             ymax=ymin+uint16(app.CallingApp.posROI(4));
             for j=1:app.CallingApp.pixel
                preROI(j)=mean(mean(mean(app.CallingApp.currentThreshData(ymin:ymax,xmin:xmax,app.CallingApp.actFr(app.frame)-app.mOverFr:app.CallingApp.actFr(app.frame)-1))));
                postROI(j)=mean(mean(mean(app.CallingApp.currentThreshData(ymin:ymax,xmin:xmax,app.CallingApp.actFr(app.frame)+1:app.CallingApp.actFr(app.frame)+app.mOverFr))));
                dFROI(j)=(postROI(j)-preROI(j))/preROI(j)*100;
                xmin=xmin+1;
                xmax=xmax+1; 
                if xmax>app.CallingApp.pixel
                    xmax=app.CallingApp.pixel;
                end
             end
             lev = app.CallingApp.thresh; % Level for Color Change
             aboveLine = (dFROI>=lev); % Find points above the level
             topLine = dFROI;
             bottomLine = dFROI;
             bottomLine(aboveLine) = NaN;
             topLine(~aboveLine) = NaN;
             xp=1-int16(app.CallingApp.posROI(1)):app.CallingApp.pixel-int16(app.CallingApp.posROI(1)); %array of frames numbers with zero in starting ROI x 
             title(app.horz, ['Horizontal sliding ROI at time ' num2str(uint16(app.CallingApp.actFr(app.frame)*app.CallingApp.framePeriod)) 's']);
             plot(app.horz, xp, bottomLine, 'b', xp, topLine, 'r')
             line(app.horz, [0 0], ylim(app.horz), 'Color', 'g');
        end
    
        function plotSlidingROIy(app)
             xmin=uint16(app.CallingApp.posROI(1));
             ymin=1;
             xmax=xmin+uint16(app.CallingApp.posROI(3));
             ymax=uint16(app.CallingApp.posROI(4));
             for j=1:app.CallingApp.pixel
                preROI(j)=mean(mean(mean(app.CallingApp.currentThreshData(ymin:ymax,xmin:xmax,app.CallingApp.actFr(app.frame)-app.mOverFr:app.CallingApp.actFr(app.frame)-1))));
                postROI(j)=mean(mean(mean(app.CallingApp.currentThreshData(ymin:ymax,xmin:xmax,app.CallingApp.actFr(app.frame)+1:app.CallingApp.actFr(app.frame)+app.mOverFr))));
                dFROI(j)=(postROI(j)-preROI(j))/preROI(j)*100;
                ymin=ymin+1;
                ymax=ymax+1; 
                if ymax>app.CallingApp.pixel
                    ymax=app.CallingApp.pixel;
                end
             end
             lev = app.CallingApp.thresh; % Level for Color Change
             aboveLine = (dFROI>=lev); % Find points above the level
             topLine = dFROI;
             bottomLine=dFROI;
             bottomLine(aboveLine) = NaN;
             topLine(~aboveLine) = NaN;
             xp=1-int16(app.CallingApp.posROI(2)):app.CallingApp.pixel-int16(app.CallingApp.posROI(2)); %array of frames numbers with zero in starting ROI x 
             title(app.vert, ['Vertical sliding ROI at time ' num2str(uint16(app.CallingApp.actFr(app.frame)*app.CallingApp.framePeriod)) 's']);
             plot(app.vert, xp, bottomLine, 'b', xp, topLine, 'r')
             line(app.vert, [0 0], ylim(app.vert), 'Color', 'm');
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, fr)
            app.CallingApp=mainApp;
            app.frame=fr;
            app.AveragetimeforframecomparisonsEditField.Value=1;
            app.mOverFr=uint16(app.AveragetimeforframecomparisonsEditField.Value/app.CallingApp.framePeriod);
            app.UIFigure.Name=['Sliding ROI profile for activation #' num2str(app.frame)];
            xlim(app.UIAxes, [0 app.CallingApp.pixel]);
            ylim(app.UIAxes, [0 app.CallingApp.pixel]);
            imagesc(app.UIAxes, mean(app.CallingApp.currentThreshData(:,:,app.CallingApp.actFr(app.frame)+1:app.CallingApp.actFr(app.frame)+app.mOverFr),3)-mean(app.CallingApp.currentThreshData(:,:,app.CallingApp.actFr(app.frame)-app.mOverFr:app.CallingApp.actFr(app.frame)-1),3))
             rectangle(app.UIAxes, 'Position',app.CallingApp.posROI,'EdgeColor','y');
             xmin=uint16(app.CallingApp.posROI(1));
             ymin=uint16(app.CallingApp.posROI(2));
             xmax=xmin+uint16(app.CallingApp.posROI(3));
             ymax=ymin+uint16(app.CallingApp.posROI(4));
             hold(app.UIAxes, 'on');
             line(app.UIAxes, xlim(app.UIAxes), [ymin ymin], 'Color', 'g');
             line(app.UIAxes, xlim(app.UIAxes), [ymax ymax], 'Color', 'g');
             line(app.UIAxes, [xmin xmin], ylim(app.UIAxes), 'Color', 'm');
             line(app.UIAxes, [xmax xmax], ylim(app.UIAxes), 'Color', 'm');
%              title(app.vert, ['Vertical sliding ROI at time ' num2str(uint16(app.CallingApp.actFr(app.frame)*app.CallingApp.framePeriod)) 's']);
%              plot(app.vert, app.CallingApp.ypx, app.CallingApp.bottomLiney(:,app.CallingApp.actFr(app.frame)), 'b', app.CallingApp.ypx, app.CallingApp.topLiney(:,app.CallingApp.actFr(app.frame)), 'r')
%              line(app.vert, [0 0], ylim(app.vert), 'Color', 'g');
%              title(app.horz, ['Horizontal sliding ROI at time ' num2str(uint16(app.CallingApp.actFr(app.frame)*app.CallingApp.framePeriod)) 's']);
%              plot(app.horz, app.CallingApp.xpx, app.CallingApp.bottomLinex(:,app.CallingApp.actFr(app.frame)), 'b', app.CallingApp.xpx, app.CallingApp.topLinex(:,app.CallingApp.actFr(app.frame)), 'r')
%              line(app.horz, [0 0], ylim(app.horz), 'Color', 'm');
             plotSlidingROIx(app)
             plotSlidingROIy(app)
        end

        % Value changed function: 
        % AveragetimeforframecomparisonsEditField
        function AveragetimeforframecomparisonsEditFieldValueChanged(app, event)
            value = app.AveragetimeforframecomparisonsEditField.Value;
            app.mOverFr=uint16(value/app.CallingApp.framePeriod);
            imagesc(app.UIAxes, mean(app.CallingApp.currentThreshData(:,:,app.CallingApp.actFr(app.frame)+1:app.CallingApp.actFr(app.frame)+app.mOverFr),3)-mean(app.CallingApp.currentThreshData(:,:,app.CallingApp.actFr(app.frame)-app.mOverFr:app.CallingApp.actFr(app.frame)-1),3))
             rectangle(app.UIAxes, 'Position',app.CallingApp.posROI,'EdgeColor','y');
             xmin=uint16(app.CallingApp.posROI(1));
             ymin=uint16(app.CallingApp.posROI(2));
             xmax=xmin+uint16(app.CallingApp.posROI(3));
             ymax=ymin+uint16(app.CallingApp.posROI(4));
             hold(app.UIAxes, 'on');
             line(app.UIAxes, xlim(app.UIAxes), [ymin ymin], 'Color', 'g');
             line(app.UIAxes, xlim(app.UIAxes), [ymax ymax], 'Color', 'g');
             line(app.UIAxes, [xmin xmin], ylim(app.UIAxes), 'Color', 'm');
             line(app.UIAxes, [xmax xmax], ylim(app.UIAxes), 'Color', 'm');
            plotSlidingROIx(app)
            plotSlidingROIy(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.horz = uiaxes(app.UIFigure);
            title(app.horz, 'Title')
            xlabel(app.horz, 'ROI dist_x from ref-ROI [pixel]')
            ylabel(app.horz, 'dF/F0 [%]')
            app.vert = uiaxes(app.UIFigure);
            title(app.vert, 'Title')
            xlabel(app.vert, 'ROI dist_y from ref-ROI [pixel]')
            ylabel(app.vert, 'dF/F0 [%]')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '2D dF')
            app.AveragetimeforframecomparisonsEditFieldLabel = uilabel(app.UIFigure);
            app.AveragetimeforframecomparisonsEditFieldLabel.Text = 'Average time  for frame comparison [s]';
            app.AveragetimeforframecomparisonsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AveragetimeforframecomparisonsEditField.Limits = [0.1 Inf];
            app.AveragetimeforframecomparisonsEditField.ValueChangedFcn = createCallbackFcn(app, @AveragetimeforframecomparisonsEditFieldValueChanged, true);
            app.AveragetimeforframecomparisonsEditField.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = activationProfile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end