classdef circularROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        StepsizepixelEditFieldLabel  matlab.ui.control.Label
        StepsizepixelEditField       matlab.ui.control.NumericEditField
        UIAxes2                      matlab.ui.control.UIAxes
        FrameSliderLabel             matlab.ui.control.Label
        FrameSlider                  matlab.ui.control.Slider
        EditField                    matlab.ui.control.NumericEditField
        thresholdEditFieldLabel      matlab.ui.control.Label
        thresholdEditField           matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp % Description
    end

    properties (Access = public)
        currentFr
        threshData
        acceptROIs
        posROI
        rROI
        radii
        profROI
        BFr
        BFrstart
        thresh
    end

    methods (Access = private)
    
        function circle(app,ax,x,y,r)
            rd=r;
            app.radii=zeros(uint16(app.CallingApp.pixel/r),1);
            for i=1:uint16(app.CallingApp.pixel/r)
                hold (ax, 'on')
                th = 0:pi/50:2*pi;
                xunit(i,:) = rd * cos(th) + x;
                yunit(i,:) = rd * sin(th) + y;
                plot(ax,xunit(i,:), yunit(i,:),'r');
                app.radii(i,1)=rd;
                rd=rd+r;
            end
        end
        
        function acceptData = insideR(app, x, y, r, R)
                acceptData=zeros(app.CallingApp.pixel);
                for j=1:app.CallingApp.pixel
                    for i=1:app.CallingApp.pixel
                        d=sqrt(abs(x-j)^2+abs(y-i)^2);
                        accept=(r<d)&&(d<R);
                        acceptData(i,j)= accept;
                    end
                end
        end
        
        function intensityROI(app)
            circle(app,app.UIAxes, app.posROI(1), app.posROI(2), app.rROI);
            app.acceptROIs=zeros(app.CallingApp.pixel,app.CallingApp.pixel,size(app.radii,1));
            app.profROI=zeros(size(app.radii,1),app.CallingApp.newnumFrames);
            for i=1:size(app.radii,1)
                if i==1
                    app.acceptROIs(:,:,i)=insideR(app, app.posROI(1), app.posROI(2), 0, app.radii(i,1));
                else
                    app.acceptROIs(:,:,i)=insideR(app, app.posROI(1), app.posROI(2), app.radii(i-1,1), app.radii(i,1));
                end
            end
            F02D=mean(app.threshData(:,:,app.BFrstart:app.BFr),3);
            for i=1:size(app.radii,1)
                F0ref=nonzeros(F02D*app.acceptROIs(:,:,i));
                F0=mean(mean(F0ref));
                for k=1:app.CallingApp.newnumFrames
                    prof=nonzeros(app.threshData(:,:,k)*app.acceptROIs(:,:,i));
                    app.profROI(i,k)=(mean(mean(prof))-F0)/F0*100;
                end
            end
            xlim(app.UIAxes, [0 app.CallingApp.pixel])
            ylim(app.UIAxes, [0 app.CallingApp.pixel])
            hold(app.UIAxes, 'on');
            imagesc(app.UIAxes, app.threshData(:,:,1))
            plot(app.UIAxes, app.posROI(1), app.posROI(2), 'r*');
            circle(app,app.UIAxes, app.posROI(1), app.posROI(2), app.rROI);
        end
        
        function plotProfROIs(app)
            xlim(app.UIAxes2, [0 size(app.radii,1)])
            lev = app.thresh; % Level for Color Change
            aboveLine = (app.profROI>=lev); % Find points above the level
            bottomLine = app.profROI;
            topLine = app.profROI;
            bottomLine(aboveLine) = NaN;
            topLine(~aboveLine) = NaN;
            %app.ypx=1-int16(app.posROI(2)):app.pixel-int16(app.posROI(2)); %array of frames numbers with zero in starting ROI x 
            plot(app.UIAxes2, 1:size(app.radii,1), bottomLine(:,app.currentFr), 'b', 1:size(app.radii,1), topLine(:,app.currentFr), 'r')
            %plot(app.UIAxes2, 1:size(app.radii,2), app.profROI(:,app.currentFr), 'y')
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)

            app.CallingApp=mainApp;
            app.BFr=uint16(app.CallingApp.BT / app.CallingApp.framePeriod);
            app.BFrstart=uint16(app.CallingApp.BTstart/app.CallingApp.framePeriod);
            if app.BFrstart==0
                app.BFrstart=1;
            end
            app.FrameSlider.Limits=[1 app.CallingApp.newnumFrames];
            app.currentFr=1;
            app.thresh=app.thresholdEditField.Value;
            app.threshData=app.CallingApp.currentThreshData;
            app.posROI=app.CallingApp.posROI;
            app.rROI=10;
            intensityROI(app)
            plotProfROIs(app);
        end

        % Value changed function: StepsizepixelEditField
        function StepsizepixelEditFieldValueChanged(app, event)
            app.rROI = app.StepsizepixelEditField.Value;
            intensityROI(app)
            plotProfROIs(app);
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            app.currentFr = uint16(event.Value);
            app.EditField.Value= double(app.currentFr);
            plotProfROIs(app);
            hold(app.UIAxes, 'on');
            imagesc(app.UIAxes, app.threshData(:,:,app.currentFr))
            plot(app.UIAxes, app.posROI(1), app.posROI(2), 'r*');
            circle(app,app.UIAxes, app.posROI(1), app.posROI(2), app.rROI);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.currentFr = uint16(app.EditField.Value);
            app.FrameSlider.Value= double(app.currentFr);
            plotProfROIs(app);
            hold(app.UIAxes, 'on');
            imagesc(app.UIAxes, app.threshData(:,:,app.currentFr))
            plot(app.UIAxes, app.posROI(1), app.posROI(2), 'r*');
            circle(app,app.UIAxes, app.posROI(1), app.posROI(2), app.rROI);
        end

        % Value changed function: thresholdEditField
        function thresholdEditFieldValueChanged(app, event)
            app.thresh = app.thresholdEditField.Value;
            plotProfROIs(app);
            hold(app.UIAxes, 'on');
            imagesc(app.UIAxes, app.threshData(:,:,app.currentFr))
            plot(app.UIAxes, app.posROI(1), app.posROI(2), 'r*');
            circle(app,app.UIAxes, app.posROI(1), app.posROI(2), app.rROI);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.StepsizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.StepsizepixelEditFieldLabel.Text = 'Step size (pixel)';
            app.StepsizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsizepixelEditField.Limits = [1 Inf];
            app.StepsizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizepixelEditFieldValueChanged, true);
            app.StepsizepixelEditField.Value = 10;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Intensity with distance from reference point')
            xlabel(app.UIAxes2, 'ROI')
            ylabel(app.UIAxes2, 'dF/F0 [%]')
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 1;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.thresholdEditFieldLabel = uilabel(app.UIFigure);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.thresholdEditField.Limits = [0 Inf];
            app.thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @thresholdEditFieldValueChanged, true);
            app.thresholdEditField.Value = 10;
        end
    end

    methods (Access = public)

        % Construct app
        function app = circularROI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end