classdef filterTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        FrameLabel                matlab.ui.control.Label
        FrameSlider               matlab.ui.control.Slider
        Frame                     matlab.ui.control.NumericEditField
        UIAxes3                   matlab.ui.control.UIAxes
        tfiltEditFieldLabel       matlab.ui.control.Label
        tfiltEditField            matlab.ui.control.NumericEditField
        xfiltEditFieldLabel       matlab.ui.control.Label
        xfiltEditField            matlab.ui.control.NumericEditField
        SavevideoButton           matlab.ui.control.Button
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        UIAxes6                   matlab.ui.control.UIAxes
        CompareframesLabel        matlab.ui.control.Label
        BetweenEditFieldLabel     matlab.ui.control.Label
        fromEditField             matlab.ui.control.NumericEditField
        toEditField               matlab.ui.control.NumericEditField
        BetweenEditFieldLabel_2   matlab.ui.control.Label
        fromEditField_2           matlab.ui.control.NumericEditField
        toEditField_2             matlab.ui.control.NumericEditField
        CminEditField             matlab.ui.control.NumericEditField
        CmaxEditField             matlab.ui.control.NumericEditField
        SpatialfilterButtonGroup  matlab.ui.container.ButtonGroup
        AverageXButton            matlab.ui.control.RadioButton
        GaussianButton            matlab.ui.control.RadioButton
        Label_3                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
        data
        filterData
        profile
        filterProfile
        frames
        pixels
        currentFr
        Cmin
        Cmax
    end
    
    methods (Access = private)
        
        function filtSpaceTime(app)
            app.Label_3.Text='Wait, filtering...';
            %spatial
            if app.AverageXButton.Value==1
                disp('Average filter applied')
                h = fspecial('average',app.xfiltEditField.Value);
                for i=1:app.frames
                    app.filterData(:,:,i)=filter2(h,app.data(:,:,i));
                end 
            else
                disp('Gaussian filter applied')
                for i=1:app.frames
                    app.filterData(:,:,i)=imgaussfilt(app.data(:,:,i),app.xfiltEditField.Value);
                end 
            end
            %temporal
            d1 = designfilt('lowpassiir','FilterOrder',12,'HalfPowerFrequency',app.tfiltEditField.Value,'DesignMethod','butter');
            newFiltered=zeros([app.pixels app.pixels app.frames]);
            for n=1:app.pixels
                for m=1:app.pixels
                    newFiltered(m,n,:)=filtfilt(d1,double(squeeze(app.filterData(m,n,:))));
                end
            end
            app.filterData=newFiltered;
            app.filterProfile=squeeze(mean(mean(app.filterData)));
            app.Label_3.Text='';
            plotData(app)
        end

        
        function plotData(app)
            imagesc(app.UIAxes, app.data(:,:,app.currentFr))
            imagesc(app.UIAxes3, app.filterData(:,:,app.currentFr), [app.Cmin app.Cmax])
            colorbar(app.UIAxes3)
            hold(app.UIAxes2, 'off');
            plot(app.UIAxes2, app.profile)
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, app.filterProfile)
            xline(app.UIAxes2, app.currentFr, 'Linewidth', 2, 'Color','r');
            compareFr(app)
        end
        
        function compareFr(app)
            pre=mean(app.filterData(:,:,app.fromEditField.Value:app.toEditField.Value),3);
            post=mean(app.filterData(:,:,app.fromEditField_2.Value:app.toEditField_2.Value),3);
            imagesc(app.UIAxes4, pre)
            imagesc(app.UIAxes5, post)
            imagesc(app.UIAxes6, pre-post)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp=mainApp;
            app.UIFigure.Name=['Filtering tool - ' app.CallingApp.name];
            app.data=app.CallingApp.data;
            app.filterData=app.CallingApp.data;
            app.frames=app.CallingApp.frames;
            app.pixels=app.CallingApp.pixel;
            app.currentFr = 1;
            app.FrameSlider.Value=1;
            app.Frame.Value=1;
            app.FrameSlider.Limits = [1 app.frames];
            app.Frame.Limits = [1 app.frames];
            app.Cmin=min(min(min(app.data)));
            app.Cmax=max(max(max(app.data)));
            app.CminEditField.Value=double(min(min(min(app.data))));
            app.CmaxEditField.Value=double(max(max(max(app.data))));
            xlim(app.UIAxes, [0 app.pixels]);
            ylim(app.UIAxes, [0 app.pixels]);
            xlim(app.UIAxes3, [0 app.pixels]);
            ylim(app.UIAxes3, [0 app.pixels]);
            xlim(app.UIAxes4, [0 app.pixels]);
            ylim(app.UIAxes4, [0 app.pixels]);
            xlim(app.UIAxes5, [0 app.pixels]);
            ylim(app.UIAxes5, [0 app.pixels]);
            xlim(app.UIAxes6, [0 app.pixels]);
            ylim(app.UIAxes6, [0 app.pixels]);
            colorbar(app.UIAxes6)
            imagesc(app.UIAxes, app.data(:,:,app.currentFr))
            app.profile=squeeze(mean(mean(app.data)));
            app.filterProfile=squeeze(mean(mean(app.filterData)));
            plotData(app)
            compareFr(app)
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)

            app.currentFr = uint16(event.Value);
            app.Frame.Value=double(app.currentFr);
            plotData(app)
        end

        % Value changed function: Frame
        function FrameValueChanged(app, event)
            app.currentFr = app.Frame.Value;
            app.FrameSlider.Value=double(app.currentFr);
            plotData(app)
        end

        % Value changed function: tfiltEditField
        function tfiltEditFieldValueChanged(app, event)

            filtSpaceTime(app)
        end

        % Value changed function: xfiltEditField
        function xfiltEditFieldValueChanged(app, event)

            filtSpaceTime(app)
        end

        % Button pushed function: SavevideoButton
        function SavevideoButtonPushed(app, event)
            selpath = uigetdir('C:\Users\mirko.zanon\Desktop');
            prompt = {'Video name:'};
            dlgtitle = 'Video name';
            nm=inputdlg(prompt,dlgtitle);
            nameV=cell2mat(nm);
            v = VideoWriter([selpath '\' nameV '.avi']);
            prompt = {'Frame rate:'};
            dlgtitle = 'Frame rate';
            fR=inputdlg(prompt,dlgtitle);
            frameR=str2double(cell2mat(fR));
            v.FrameRate=frameR;
            open(v);
            for i = 1:app.frames
                I=mat2gray(app.filterData(:,:,i));
                imwrite(I,'frame.jpg');
                fr=imread('frame.jpg');
                writeVideo(v,fr);
            end
            delete('frame.jpg')
            close(v);
        end

        % Value changed function: fromEditField
        function fromEditFieldValueChanged(app, event)

            compareFr(app)
        end

        % Value changed function: toEditField
        function toEditFieldValueChanged(app, event)

            compareFr(app)
        end

        % Value changed function: fromEditField_2
        function fromEditField_2ValueChanged(app, event)

            compareFr(app)
        end

        % Value changed function: toEditField_2
        function toEditField_2ValueChanged(app, event)

            compareFr(app)
        end

        % Value changed function: CminEditField
        function CminEditFieldValueChanged(app, event)
            app.Cmin = app.CminEditField.Value;
            plotData(app)
        end

        % Value changed function: CmaxEditField
        function CmaxEditFieldValueChanged(app, event)
            app.Cmax = app.CmaxEditField.Value;
            plotData(app)
        end

        % Selection changed function: SpatialfilterButtonGroup
        function SpatialfilterButtonGroupSelectionChanged(app, event)

            app.filterData=app.data;
            app.filterProfile=squeeze(mean(mean(app.filterData)));
            if app.AverageXButton.Value==1
                app.xfiltEditField.Value=1;
                app.xfiltEditField.Limits=[1, Inf];
            else
                app.xfiltEditField.Limits=[0.000001, Inf];
                app.xfiltEditField.Value=0.000001;
            end
            filtSpaceTime(app)
            plotData(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw data')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Intensity profile')
            xlabel(app.UIAxes2, 'Frames')
            ylabel(app.UIAxes2, 'F [a.u.]')
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = {'Frame'; ''};
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [1 400];
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 1;
            app.Frame = uieditfield(app.UIFigure, 'numeric');
            app.Frame.Limits = [1 Inf];
            app.Frame.ValueChangedFcn = createCallbackFcn(app, @FrameValueChanged, true);
            app.Frame.Value = 1;
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Filtered data')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.tfiltEditFieldLabel = uilabel(app.UIFigure);
            app.tfiltEditFieldLabel.Text = 't filt';
            app.tfiltEditField = uieditfield(app.UIFigure, 'numeric');
            app.tfiltEditField.Limits = [1e-06 1];
            app.tfiltEditField.ValueChangedFcn = createCallbackFcn(app, @tfiltEditFieldValueChanged, true);
            app.tfiltEditField.Value = 1;
            app.xfiltEditFieldLabel = uilabel(app.UIFigure);
            app.xfiltEditFieldLabel.Text = 'x filt';
            app.xfiltEditField = uieditfield(app.UIFigure, 'numeric');
            app.xfiltEditField.Limits = [1e-06 Inf];
            app.xfiltEditField.ValueChangedFcn = createCallbackFcn(app, @xfiltEditFieldValueChanged, true);
            app.xfiltEditField.Value = 1e-06;
            app.SavevideoButton = uibutton(app.UIFigure, 'push');
            app.SavevideoButton.ButtonPushedFcn = createCallbackFcn(app, @SavevideoButtonPushed, true);
            app.SavevideoButton.Text = 'Save video';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Avg1')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Avg2')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Avg1-Avg2')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.CompareframesLabel = uilabel(app.UIFigure);
            app.CompareframesLabel.Text = 'Compare frames:';
            app.BetweenEditFieldLabel = uilabel(app.UIFigure);
            app.BetweenEditFieldLabel.Text = 'Between';
            app.fromEditField = uieditfield(app.UIFigure, 'numeric');
            app.fromEditField.Limits = [1 Inf];
            app.fromEditField.ValueChangedFcn = createCallbackFcn(app, @fromEditFieldValueChanged, true);
            app.fromEditField.Value = 1;
            app.toEditField = uieditfield(app.UIFigure, 'numeric');
            app.toEditField.Limits = [1 Inf];
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.toEditField.Value = 1;
            app.BetweenEditFieldLabel_2 = uilabel(app.UIFigure);
            app.BetweenEditFieldLabel_2.Text = 'Between';
            app.fromEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.fromEditField_2.Limits = [1 Inf];
            app.fromEditField_2.ValueChangedFcn = createCallbackFcn(app, @fromEditField_2ValueChanged, true);
            app.fromEditField_2.Value = 1;
            app.toEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.toEditField_2.Limits = [1 Inf];
            app.toEditField_2.ValueChangedFcn = createCallbackFcn(app, @toEditField_2ValueChanged, true);
            app.toEditField_2.Value = 1;
            app.CminEditField = uieditfield(app.UIFigure, 'numeric');
            app.CminEditField.ValueChangedFcn = createCallbackFcn(app, @CminEditFieldValueChanged, true);
            app.CmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.CmaxEditField.ValueChangedFcn = createCallbackFcn(app, @CmaxEditFieldValueChanged, true);
            app.CmaxEditField.Value = 500;
            app.SpatialfilterButtonGroup = uibuttongroup(app.UIFigure);
            app.SpatialfilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpatialfilterButtonGroupSelectionChanged, true);
            app.SpatialfilterButtonGroup.Title = 'Spatial filter';
            app.AverageXButton = uiradiobutton(app.SpatialfilterButtonGroup);
            app.AverageXButton.Text = 'AverageX';
            app.GaussianButton = uiradiobutton(app.SpatialfilterButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.GaussianButton.Value = true;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filterTool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end