classdef slidingROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        FrameSliderLabel              matlab.ui.control.Label
        FrameSlider                   matlab.ui.control.Slider
        Frame                         matlab.ui.control.NumericEditField
        FramerateLabel                matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        ActvationframesLabel          matlab.ui.control.Label
        ThresholdactivationEditFieldLabel  matlab.ui.control.Label
        ThresholdactivationEditField  matlab.ui.control.NumericEditField
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        NewslidingROIButton           matlab.ui.control.Button
        PlotforactivationtimesButton  matlab.ui.control.Button
        horz                          matlab.ui.control.UIAxes
        vert                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp % Description
    end

    properties (Access = public)
        currentFr % Description
        actFr
        currentThreshData
        newnumFrames
        pixel
        posROI
        thresh
        framePeriod
        BT
        BTstart
        actTimes
        delay
        bottomLinex
        topLinex
        bottomLiney
        topLiney
        xpx
        ypx
    end

    methods (Access = public)
    
        function plotSlidingROIx(app)
             %xmin=uint16(app.posROI(1))
             xmin=1;
             ymin=uint16(app.posROI(2));
             %xmax=xmin+uint16(app.posROI(3));
             xmax=uint16(app.posROI(3));
             ymax=ymin+uint16(app.posROI(4));
             F0ROI=zeros(app.pixel);
             profileROI=zeros(app.pixel,app.newnumFrames);
             dFROI=zeros(app.pixel,app.newnumFrames);
             for j=1:app.pixel
                for i=1:app.newnumFrames
                    profileRoi(j,i)=mean(mean(app.currentThreshData(ymin:ymax,xmin:xmax,i)));
                end
                %rectangle(app.UIAxes,'Position', [xmin,ymin,xmax-xmin,ymax-ymin]);
                xmin=xmin+1;
                xmax=xmax+1; 
                BTi=uint16(app.CallingApp.BTstart/app.CallingApp.framePeriod);
                BTf=uint16(app.CallingApp.BT/app.CallingApp.framePeriod);
                if BTi==0 
                    BTi=1;
                end
                if BTf==0 
                    BTf=1;
                end 
                F0ROI(j)=mean(profileRoi(j, BTi:BTf));
                dFROI(j,:)=(profileRoi(j,:)-F0ROI(j))/F0ROI(j)*100;
                if xmax>app.pixel
                    xmax=app.pixel;
                end
             end
             lev = app.thresh; % Level for Color Change
             aboveLine = (dFROI>=lev); % Find points above the level
             app.bottomLinex = dFROI;
             app.topLinex = dFROI;
             app.bottomLinex(aboveLine) = NaN;
             app.topLinex(~aboveLine) = NaN;
             app.xpx=1-int16(app.posROI(1)):app.pixel-int16(app.posROI(1)); %array of frames numbers with zero in starting ROI x 
             title(app.UIAxes2, ['Horizontal sliding ROI at time ' num2str(uint16(app.currentFr*app.CallingApp.framePeriod)) 's']);
             plot(app.UIAxes2, app.xpx, app.bottomLinex(:,app.currentFr), 'b', app.xpx, app.topLinex(:,app.currentFr), 'r')
             line(app.UIAxes2, [0 0], ylim(app.UIAxes2), 'Color', 'g');
        end
    
        function plotSlidingROIy(app)
             xmin=uint16(app.posROI(1));
             ymin=1;
             %ymin=uint16(app.posROI(2));
             xmax=xmin+uint16(app.posROI(3));
             ymax=uint16(app.posROI(4));
             %ymax=ymin+uint16(app.posROI(4));
             F0ROI=zeros(app.pixel);
             profileROI=zeros(app.pixel,app.newnumFrames);
             dFROI=zeros(app.pixel,app.newnumFrames);
             for j=1:app.pixel
                for i=1:app.newnumFrames
                    profileRoi(j,i)=mean(mean(app.currentThreshData(ymin:ymax,xmin:xmax,i)));
                end
                %rectangle(app.UIAxes,'Position', [xmin,ymin,xmax-xmin,ymax-ymin]);
                ymin=ymin+1;
                ymax=ymax+1; 
                BTi=uint16(app.CallingApp.BTstart/app.CallingApp.framePeriod);
                BTf=uint16(app.CallingApp.BT/app.CallingApp.framePeriod);
                if BTi==0 
                    BTi=1;
                end
                if BTf==0 
                    BTf=1;
                end 
                F0ROI(j)=mean(profileRoi(j, BTi:BTf));
                dFROI(j,:)=(profileRoi(j,:)-F0ROI(j))/F0ROI(j)*100;
                if ymax>app.pixel
                    ymax=app.pixel;
                end
             end
             lev = app.thresh; % Level for Color Change
             aboveLine = (dFROI>=lev); % Find points above the level
             app.bottomLiney = dFROI;
             app.topLiney = dFROI;
             app.bottomLiney(aboveLine) = NaN;
             app.topLiney(~aboveLine) = NaN;
             app.ypx=1-int16(app.posROI(2)):app.pixel-int16(app.posROI(2)); %array of frames numbers with zero in starting ROI x 
             title(app.UIAxes3, ['Vertical sliding ROI at time ' num2str(uint16(app.currentFr*app.CallingApp.framePeriod)) 's']);
             plot(app.UIAxes3, app.ypx, app.bottomLiney(:,app.currentFr), 'b', app.ypx, app.topLiney(:,app.currentFr), 'r')
             line(app.UIAxes3, [0 0], ylim(app.UIAxes3), 'Color', 'm');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
             app.CallingApp=mainApp;
             app.UIFigure.Name=['Sliding ROI analysis'];
             app.thresh=app.ThresholdactivationEditField.Value;
             app.currentFr=1;
             app.framePeriod=app.CallingApp.framePeriod;
             app.BT=app.CallingApp.BT;
             app.BTstart=app.CallingApp.BTstart;
             app.actTimes=app.CallingApp.actTimes;
             app.delay=app.CallingApp.delay;
             for i=1:size(app.CallingApp.actTimes,2)
                app.actFr(i)=uint16((app.CallingApp.actTimes(i)-app.CallingApp.delay(i))/app.CallingApp.framePeriod);
             end
            app.ActvationframesLabel.Text=['Activation frames: ' num2str(app.actFr)];
             app.currentThreshData=app.CallingApp.currentThreshData;
             app.posROI=app.CallingApp.posROI;
             app.newnumFrames=app.CallingApp.newnumFrames;
             app.pixel=app.CallingApp.pixel;
             app.FrameSlider.Limits=[1 app.CallingApp.newnumFrames];
             app.Frame.Limits=[1 app.CallingApp.newnumFrames];
             app.FramerateLabel.Text=['One frame is ' num2str(app.CallingApp.framePeriod) 's'];
             imagesc(app.UIAxes, app.CallingApp.currentThreshData(:,:,1))
             rectangle(app.UIAxes, 'Position',app.posROI,'EdgeColor','y');
             xlim(app.UIAxes, [0 app.CallingApp.pixel]);
             ylim(app.UIAxes, [0 app.CallingApp.pixel]);
             xmin=uint16(app.posROI(1));
             ymin=uint16(app.posROI(2));
             xmax=xmin+uint16(app.posROI(3));
             ymax=ymin+uint16(app.posROI(4));
             hold(app.UIAxes, 'on');
             line(app.UIAxes, xlim(app.UIAxes), [ymin ymin], 'Color', 'g');
             line(app.UIAxes, xlim(app.UIAxes), [ymax ymax], 'Color', 'g');
             line(app.UIAxes, [xmin xmin], ylim(app.UIAxes), 'Color', 'm');
             line(app.UIAxes, [xmax xmax], ylim(app.UIAxes), 'Color', 'm');
             plotSlidingROIx(app)
             plotSlidingROIy(app)
             
             xlim(app.horz, [0 app.CallingApp.pixel]);
             ylim(app.horz, [0 uint16(app.posROI(4))]);
             imagesc(app.horz, app.CallingApp.currentThreshData(ymin:ymax,:,1))
             line(app.horz, [xmin xmin], ylim(app.horz), 'Color', 'g');
             line(app.horz, [xmax xmax], ylim(app.horz), 'Color', 'g');
             
             xlim(app.vert, [0 app.CallingApp.pixel]);
             ylim(app.vert, [0 uint16(app.posROI(3))]);
             imagesc(app.vert, app.CallingApp.currentThreshData(:,flip(xmin:xmax),1)')
             line(app.vert, [ymin ymin], ylim(app.vert), 'Color', 'm');
             line(app.vert, [ymax ymax], ylim(app.vert), 'Color', 'm');
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            app.currentFr=uint16(event.Value);
            app.Frame.Value=double(app.currentFr);
            imagesc(app.UIAxes, app.currentThreshData(:,:,app.currentFr))
            rectangle(app.UIAxes, 'Position',app.posROI,'EdgeColor','y');
            xlim(app.UIAxes, [0 app.pixel]);
             ylim(app.UIAxes, [0 app.pixel]);
             xmin=uint16(app.posROI(1));
             ymin=uint16(app.posROI(2));
             xmax=xmin+uint16(app.posROI(3));
             ymax=ymin+uint16(app.posROI(4));
             hold(app.UIAxes, 'on');
             line(app.UIAxes, xlim(app.UIAxes), [ymin ymin], 'Color', 'g');
             line(app.UIAxes, xlim(app.UIAxes), [ymax ymax], 'Color', 'g');
             line(app.UIAxes, [xmin xmin], ylim(app.UIAxes), 'Color', 'm');
             line(app.UIAxes, [xmax xmax], ylim(app.UIAxes), 'Color', 'm');
             plotSlidingROIx(app)
             plotSlidingROIy(app)
             
             xlim(app.horz, [0 app.CallingApp.pixel]);
             ylim(app.horz, [0 uint16(app.posROI(4))]);
             imagesc(app.horz, app.CallingApp.currentThreshData(ymin:ymax,:,app.currentFr))
             line(app.horz, [xmin xmin], ylim(app.horz), 'Color', 'g');
             line(app.horz, [xmax xmax], ylim(app.horz), 'Color', 'g');
             
             xlim(app.vert, [0 app.CallingApp.pixel]);
             ylim(app.vert, [0 uint16(app.posROI(3))]);
             imagesc(app.vert, app.CallingApp.currentThreshData(:,flip(xmin:xmax),app.currentFr)')
             line(app.vert, [ymin ymin], ylim(app.vert), 'Color', 'm');
             line(app.vert, [ymax ymax], ylim(app.vert), 'Color', 'm');
        end

        % Value changed function: Frame
        function FrameValueChanged(app, event)
            app.currentFr = app.Frame.Value;
            app.FrameSlider.Value=double(app.currentFr);
            imagesc(app.UIAxes, app.currentThreshData(:,:,app.currentFr))
            rectangle(app.UIAxes, 'Position',app.posROI,'EdgeColor','y');
            xlim(app.UIAxes, [0 app.pixel]);
             ylim(app.UIAxes, [0 app.pixel]);
             xmin=uint16(app.posROI(1));
             ymin=uint16(app.posROI(2));
             xmax=xmin+uint16(app.posROI(3));
             ymax=ymin+uint16(app.posROI(4));
             hold(app.UIAxes, 'on');
             line(app.UIAxes, xlim(app.UIAxes), [ymin ymin], 'Color', 'g');
             line(app.UIAxes, xlim(app.UIAxes), [ymax ymax], 'Color', 'g');
             line(app.UIAxes, [xmin xmin], ylim(app.UIAxes), 'Color', 'm');
             line(app.UIAxes, [xmax xmax], ylim(app.UIAxes), 'Color', 'm');
             plotSlidingROIx(app)
             plotSlidingROIy(app)
             
             xlim(app.horz, [0 app.CallingApp.pixel]);
             ylim(app.horz, [0 ymax]);
             imagesc(app.horz, app.CallingApp.currentThreshData(ymin:ymax,:,app.currentFr))
             line(app.horz, [xmin xmin], ylim(app.horz), 'Color', 'g');
             line(app.horz, [xmax xmax], ylim(app.horz), 'Color', 'g');
             
             xlim(app.vert, [0 app.CallingApp.pixel]);
             ylim(app.vert, [0 xmax]);
             imagesc(app.vert, app.CallingApp.currentThreshData(:,flip(xmin:xmax),app.currentFr)')
             line(app.vert, [ymin ymin], ylim(app.vert), 'Color', 'm');
             line(app.vert, [ymax ymax], ylim(app.vert), 'Color', 'm');
        end

        % Value changed function: ThresholdactivationEditField
        function ThresholdactivationEditFieldValueChanged(app, event)
            app.thresh = app.ThresholdactivationEditField.Value;
            plotSlidingROIx(app)
             plotSlidingROIy(app)
             
             xlim(app.horz, [0 app.CallingApp.pixel]);
             ylim(app.horz, [0 uint16(app.posROI(4))]);
             imagesc(app.horz, app.CallingApp.currentThreshData(ymin:ymax,:,app.currentFr))
             line(app.horz, [xmin xmin], ylim(app.horz), 'Color', 'g');
             line(app.horz, [xmax xmax], ylim(app.horz), 'Color', 'g');
             
             xlim(app.vert, [0 app.CallingApp.pixel]);
             ylim(app.vert, [0 uint16(app.posROI(3))]);
             imagesc(app.vert, app.CallingApp.currentThreshData(:,flip(xmin:xmax),app.currentFr)')
             line(app.vert, [ymin ymin], ylim(app.vert), 'Color', 'm');
             line(app.vert, [ymax ymax], ylim(app.vert), 'Color', 'm');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.currentFr~=1
                app.currentFr=app.currentFr-1;
                app.FrameSlider.Value=double(app.currentFr);
                app.Frame.Value=double(app.currentFr);
                imagesc(app.UIAxes, app.currentThreshData(:,:,app.currentFr))
             rectangle(app.UIAxes, 'Position',app.posROI,'EdgeColor','y');
             xlim(app.UIAxes, [0 app.pixel]);
             ylim(app.UIAxes, [0 app.pixel]);
             xmin=uint16(app.posROI(1));
             ymin=uint16(app.posROI(2));
             xmax=xmin+uint16(app.posROI(3));
             ymax=ymin+uint16(app.posROI(4));
             hold(app.UIAxes, 'on');
             line(app.UIAxes, xlim(app.UIAxes), [ymin ymin], 'Color', 'g');
             line(app.UIAxes, xlim(app.UIAxes), [ymax ymax], 'Color', 'g');
             line(app.UIAxes, [xmin xmin], ylim(app.UIAxes), 'Color', 'm');
             line(app.UIAxes, [xmax xmax], ylim(app.UIAxes), 'Color', 'm');
                plotSlidingROIx(app)
                plotSlidingROIy(app)
                
                xlim(app.horz, [0 app.CallingApp.pixel]);
             ylim(app.horz, [0 uint16(app.posROI(4))]);
             imagesc(app.horz, app.CallingApp.currentThreshData(ymin:ymax,:,app.currentFr))
             line(app.horz, [xmin xmin], ylim(app.horz), 'Color', 'g');
             line(app.horz, [xmax xmax], ylim(app.horz), 'Color', 'g');
             
             xlim(app.vert, [0 app.CallingApp.pixel]);
             ylim(app.vert, [0 uint16(app.posROI(3))]);
             imagesc(app.vert, app.CallingApp.currentThreshData(:,flip(xmin:xmax),app.currentFr)')
             line(app.vert, [ymin ymin], ylim(app.vert), 'Color', 'm');
             line(app.vert, [ymax ymax], ylim(app.vert), 'Color', 'm');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.currentFr~=app.newnumFrames
                app.currentFr=app.currentFr+1;
                app.FrameSlider.Value=double(app.currentFr);
                app.Frame.Value=double(app.currentFr);
                imagesc(app.UIAxes, app.currentThreshData(:,:,app.currentFr))
             rectangle(app.UIAxes, 'Position',app.posROI,'EdgeColor','y');
             xlim(app.UIAxes, [0 app.pixel]);
             ylim(app.UIAxes, [0 app.pixel]);
             xmin=uint16(app.posROI(1));
             ymin=uint16(app.posROI(2));
             xmax=xmin+uint16(app.posROI(3));
             ymax=ymin+uint16(app.posROI(4));
             hold(app.UIAxes, 'on');
             line(app.UIAxes, xlim(app.UIAxes), [ymin ymin], 'Color', 'g');
             line(app.UIAxes, xlim(app.UIAxes), [ymax ymax], 'Color', 'g');
             line(app.UIAxes, [xmin xmin], ylim(app.UIAxes), 'Color', 'm');
             line(app.UIAxes, [xmax xmax], ylim(app.UIAxes), 'Color', 'm');
                plotSlidingROIx(app)
                plotSlidingROIy(app)
                
                xlim(app.horz, [0 app.CallingApp.pixel]);
             ylim(app.horz, [0 uint16(app.posROI(4))]);
             imagesc(app.horz, app.CallingApp.currentThreshData(ymin:ymax,:,app.currentFr))
             line(app.horz, [xmin xmin], ylim(app.horz), 'Color', 'g');
             line(app.horz, [xmax xmax], ylim(app.horz), 'Color', 'g');
             
             xlim(app.vert, [0 app.CallingApp.pixel]);
             ylim(app.vert, [0 uint16(app.posROI(3))]);
             imagesc(app.vert, app.CallingApp.currentThreshData(:,flip(xmin:xmax),app.currentFr)')
             line(app.vert, [ymin ymin], ylim(app.vert), 'Color', 'm');
             line(app.vert, [ymax ymax], ylim(app.vert), 'Color', 'm');
            end
        end

        % Button pushed function: NewslidingROIButton
        function NewslidingROIButtonPushed(app, event)
             figure(1);
             imagesc(sum(app.currentThreshData,3));
             title('Select ROI:')
             h=imrect;
             hold on;
             app.posROI=h.getPosition;
             close
             slidingROI(app)
        end

        % Button pushed function: PlotforactivationtimesButton
        function PlotforactivationtimesButtonPushed(app, event)
            for i=1:size(app.actFr,2)
                activationProfile(app, i)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Sliding ROI of diameter')
            xlabel(app.UIAxes2, 'ROI dist_x from ref-ROI [pixel]')
            ylabel(app.UIAxes2, 'dF/F0')
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [1 10000];
            app.FrameSlider.MajorTickLabels = {'', '', '', '', '', ''};
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.Value = 3;
            app.Frame = uieditfield(app.UIFigure, 'numeric');
            app.Frame.Limits = [1 Inf];
            app.Frame.ValueDisplayFormat = '%.0f';
            app.Frame.ValueChangedFcn = createCallbackFcn(app, @FrameValueChanged, true);
            app.Frame.Value = 3;
            app.FramerateLabel = uilabel(app.UIFigure);
            app.FramerateLabel.Text = 'Framerate';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'ROI dist_y from ref-ROI [pixel]')
            ylabel(app.UIAxes3, 'dF/F0')
            app.ActvationframesLabel = uilabel(app.UIFigure);
            app.ActvationframesLabel.Enable = 'off';
            app.ActvationframesLabel.Text = 'Actvation frames';
            app.ThresholdactivationEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdactivationEditFieldLabel.Text = 'Threshold activation [%]';
            app.ThresholdactivationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdactivationEditField.Limits = [1 Inf];
            app.ThresholdactivationEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdactivationEditFieldValueChanged, true);
            app.ThresholdactivationEditField.Value = 20;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>>';
            app.NewslidingROIButton = uibutton(app.UIFigure, 'push');
            app.NewslidingROIButton.ButtonPushedFcn = createCallbackFcn(app, @NewslidingROIButtonPushed, true);
            app.NewslidingROIButton.Text = 'New sliding ROI';
            app.PlotforactivationtimesButton = uibutton(app.UIFigure, 'push');
            app.PlotforactivationtimesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotforactivationtimesButtonPushed, true);
            app.PlotforactivationtimesButton.Text = 'Plot for activation times';
            app.horz = uiaxes(app.UIFigure);
            title(app.horz, 'Horizontal slide')
            app.vert = uiaxes(app.UIFigure);
            title(app.vert, 'Vertical slide')
        end
    end

    methods (Access = public)

        % Construct app
        function app = slidingROI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end