classdef subtrFrames < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        StimulusSliderLabel             matlab.ui.control.Label
        StimulusSlider                  matlab.ui.control.Slider
        EditField                       matlab.ui.control.NumericEditField
        ThreasholdactivationEditFieldLabel  matlab.ui.control.Label
        ThreasholdactivationEditField   matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        ThreasholdinhibitionEditFieldLabel  matlab.ui.control.Label
        ThreasholdinhibitionEditField   matlab.ui.control.NumericEditField
        UIAxes3                         matlab.ui.control.UIAxes
        AvgsizepixelEditFieldLabel      matlab.ui.control.Label
        AvgsizepixelEditField           matlab.ui.control.NumericEditField
        laserP                          matlab.ui.control.UIAxes
        running                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        VisualizestimuliseparatedButton  matlab.ui.control.Button
        StimulistartingframesEditFieldLabel  matlab.ui.control.Label
        StimulistartingframesEditField  matlab.ui.control.EditField
    end


    properties (Access = private)
        CallingApp % Description
    end

    properties (Access = public)
        preFr
        postFr
        currentFr
        numAct
        preMean
        postMean
        preM
        postM
        preMeanavg
        postMeanavg
        subtrMean
        subtrMeanavg
        ordersubtrMean
        ordersubtrMeanavg
        orderacceptActavg
        acceptAct
        acceptActavg
        threshAct
        threshInh
        orderPowers
        actOrders
        powerMatrix
        Avgsize
        tMean
        framePeriod
        pixel
        threshData
    end

    methods (Access = public)
    
        function calcThresh(app)
            B=ones(app.Avgsize,app.Avgsize)/app.Avgsize^2;
            refFlu=mean(app.CallingApp.currentThreshData(:,:,1:30),3);
            for i=1:app.numAct
                app.preMean(:,:,i)=mean(app.CallingApp.currentThreshData(:,:,app.preM(i,1):app.preM(i,2)),3);
                app.postMean(:,:,i)=mean(app.CallingApp.currentThreshData(:,:,app.postM(i,1):app.postM(i,2)),3);
                app.preMeanavg(:,:,i)=conv2(app.preMean(:,:,i),B,'same');
                app.postMeanavg(:,:,i)=conv2(app.postMean(:,:,i),B,'same');
                app.subtrMean(:,:,i)=(app.postMean(:,:,i)-app.preMean(:,:,i));
                app.subtrMeanavg(:,:,i)=(app.postMeanavg(:,:,i)-app.preMeanavg(:,:,i));
%                 maxSub(i)=max(max(app.subtrMean(:,:,i)));
%                 maxSubavg(i)=max(max(app.subtrMean(:,:,i)));
%                 app.subtrMean(:,:,i)=app.subtrMean(:,:,i)/maxSub(i);
%                 app.subtrMeanavg(:,:,i)=app.subtrMeanavg(:,:,i)/maxSubavg(i);          
                app.acceptAct(:,:,i)=app.postMean(:,:,i)>refFlu+refFlu*app.threshInh/100;
                acceptInh(:,:,i)=app.postMean(:,:,i)<app.preMean(:,:,i)*app.threshInh/100;
                app.acceptActavg(:,:,i)=app.postMeanavg(:,:,i)>refFlu+refFlu*app.threshInh/100;
                acceptInhavg(:,:,i)=app.postMeanavg(:,:,i)<app.preMeanavg(:,:,i)*app.threshInh/100;
            end
%             for k=1:app.numAct
%                 orderacceptAct(:,:,k)=app.acceptAct(:,:,app.actOrders(k));
%                 orderacceptInh(:,:,k)=acceptInh(:,:,app.actOrders(k));
%                 app.orderacceptActavg(:,:,k)=app.acceptActavg(:,:,app.actOrders(k));
%                 app.ordersubtrMean(:,:,k)=app.subtrMean(:,:,app.actOrders(k));
%                 app.ordersubtrMeanavg(:,:,k)=app.subtrMeanavg(:,:,app.actOrders(k));
%             end
            
            app.orderacceptActavg=app.acceptActavg;
            app.powerMatrix=zeros(app.CallingApp.pixel);
            for i=1:app.CallingApp.pixel
                for j=1:app.CallingApp.pixel
                    for k=app.numAct:-1:1
                        if app.orderacceptActavg(i,j,k) == 1
                            app.powerMatrix(i,j)=k;
                        end
                    end
                end
            end
            
%             imagesc(app.UIAxes, app.ordersubtrMean(:,:,app.currentFr))
%             imagesc(app.running, app.ordersubtrMeanavg(:,:,app.currentFr))
%             imagesc(app.UIAxes2, orderacceptAct(:,:,app.currentFr))
%             imagesc(app.UIAxes3, orderacceptInh(:,:,app.currentFr))
%             imagesc(app.UIAxes4, app.orderacceptActavg(:,:,app.currentFr))
            imagesc(app.UIAxes, app.subtrMean(:,:,app.currentFr))
            imagesc(app.running, app.subtrMeanavg(:,:,app.currentFr))
            imagesc(app.UIAxes2, app.acceptAct(:,:,app.currentFr))
            %imagesc(app.UIAxes3, acceptInh(:,:,app.currentFr))
            imagesc(app.UIAxes4, app.acceptActavg(:,:,app.currentFr))
            xlim(app.laserP, [0 app.CallingApp.pixel]);
            ylim(app.laserP, [0 app.CallingApp.pixel]);
            imagesc(app.laserP, app.powerMatrix)
            colorbar(app.laserP, 'Ticks', 0:1:app.numAct)
        end
  
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, preM, postM)

            app.CallingApp=mainApp;
            app.preM=preM;
            app.postM=postM;
            app.tMean=app.CallingApp.tMean;
            app.StimulistartingframesEditField.Value=num2str(app.CallingApp.actStartFr);
            app.pixel=app.CallingApp.pixel;
            app.framePeriod=app.CallingApp.framePeriod;
            app.threshData=app.CallingApp.currentThreshData;
            app.UIFigure.Name=['Stimuli comparison ' app.CallingApp.name];
            app.numAct=size(app.CallingApp.actStartFr, 2);
            app.actOrders=1:app.numAct;
            app.Avgsize=5;
            xlim(app.UIAxes2, [0 app.CallingApp.pixel]);
            ylim(app.UIAxes2, [0 app.CallingApp.pixel]);
            xlim(app.UIAxes3, [0 app.CallingApp.pixel]);
            ylim(app.UIAxes3, [0 app.CallingApp.pixel]);
            xlim(app.UIAxes4, [0 app.CallingApp.pixel]);
            ylim(app.UIAxes4, [0 app.CallingApp.pixel]);
            xlim(app.UIAxes, [0 app.CallingApp.pixel]);
            ylim(app.UIAxes, [0 app.CallingApp.pixel]);
            xlim(app.running, [0 app.CallingApp.pixel]);
            ylim(app.running, [0 app.CallingApp.pixel]);
            app.currentFr=1;
            app.StimulusSlider.Limits=[1 app.numAct];
            app.threshAct=app.ThreasholdactivationEditField.Value;
            app.threshInh=app.ThreasholdinhibitionEditField.Value;
            imRef=app.CallingApp.imRef; 
            xlim(app.UIAxes5, [0 size(imRef,1)]);
            ylim(app.UIAxes5, [0 size(imRef,1)]);
            imagesc(app.UIAxes5, imRef(:,:,1:3))
            calcThresh(app)
        end

        % Value changing function: StimulusSlider
        function StimulusSliderValueChanging(app, event)
            app.currentFr = uint16(event.Value);
            app.StimulusSlider.Value=double(app.currentFr);
            app.EditField.Value = double(app.currentFr);
%             imagesc(app.UIAxes, app.ordersubtrMean(:,:,app.currentFr))
%             imagesc(app.running, app.ordersubtrMeanavg(:,:,app.currentFr))
            imagesc(app.UIAxes, app.subtrMean(:,:,app.currentFr))
            imagesc(app.running, app.subtrMeanavg(:,:,app.currentFr))
            calcThresh(app)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.currentFr = uint16(app.EditField.Value);
            app.StimulusSlider.Value= double(app.currentFr);
%             imagesc(app.UIAxes, app.ordersubtrMean(:,:,app.currentFr))
%             imagesc(app.running, app.ordersubtrMeanavg(:,:,app.currentFr))
            imagesc(app.UIAxes, app.subtrMean(:,:,app.currentFr))
            imagesc(app.running, app.subtrMeanavg(:,:,app.currentFr))
            calcThresh(app)
        end

        % Value changed function: ThreasholdactivationEditField
        function ThreasholdactivationEditFieldValueChanged(app, event)
            app.threshAct = app.ThreasholdactivationEditField.Value;
            calcThresh(app)
        end

        % Value changed function: ThreasholdinhibitionEditField
        function ThreasholdinhibitionEditFieldValueChanged(app, event)
            app.threshInh = app.ThreasholdinhibitionEditField.Value;
            calcThresh(app)
        end

        % Value changed function: AvgsizepixelEditField
        function AvgsizepixelEditFieldValueChanged(app, event)
            app.Avgsize = app.AvgsizepixelEditField.Value;
            B=ones(app.Avgsize,app.Avgsize)/app.Avgsize^2;
            for i=1:app.numAct
                app.preMean(:,:,i)=mean(app.CallingApp.currentThreshData(:,:,app.preM(i,1):app.preM(i,2)),3);
                app.postMean(:,:,i)=mean(app.CallingApp.currentThreshData(:,:,app.postM(i,1):app.postM(i,2)),3);
                app.preMeanavg(:,:,i)=conv2(app.preMean(:,:,i),B,'same');
                app.postMeanavg(:,:,i)=conv2(app.postMean(:,:,i),B,'same');
                app.subtrMean(:,:,i)=app.postMean(:,:,i)-app.preMean(:,:,i);
                app.subtrMeanavg(:,:,i)=app.postMeanavg(:,:,i)-app.preMeanavg(:,:,i);
            end
            %             imagesc(app.running, app.ordersubtrMeanavg(:,:,1))
            imagesc(app.running, app.subtrMeanavg(:,:,1))
            calcThresh(app)
        end

        % Button pushed function: VisualizestimuliseparatedButton
        function VisualizestimuliseparatedButtonPushed(app, event)

            for i=1:size(app.CallingApp.actStartFr,2)
                compareFr(app, [app.preM(i,1) app.preM(i,2)], [app.postM(i,1) app.postM(i,2)],i);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'dF 2D')
            app.StimulusSliderLabel = uilabel(app.UIFigure);
            app.StimulusSliderLabel.Text = 'Stimulus#';
            app.StimulusSlider = uislider(app.UIFigure);
            app.StimulusSlider.Limits = [1 20];
            app.StimulusSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.StimulusSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.StimulusSlider.ValueChangingFcn = createCallbackFcn(app, @StimulusSliderValueChanging, true);
            app.StimulusSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.StimulusSlider.Value = 1;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.ThreasholdactivationEditFieldLabel = uilabel(app.UIFigure);
            app.ThreasholdactivationEditFieldLabel.Text = 'Threashold activation';
            app.ThreasholdactivationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThreasholdactivationEditField.Limits = [0 Inf];
            app.ThreasholdactivationEditField.ValueChangedFcn = createCallbackFcn(app, @ThreasholdactivationEditFieldValueChanged, true);
            app.ThreasholdactivationEditField.Value = 10;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Activated areas')
            app.ThreasholdinhibitionEditFieldLabel = uilabel(app.UIFigure);
            app.ThreasholdinhibitionEditFieldLabel.Text = 'Threashold inhibition';
            app.ThreasholdinhibitionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThreasholdinhibitionEditField.Limits = [0 Inf];
            app.ThreasholdinhibitionEditField.ValueChangedFcn = createCallbackFcn(app, @ThreasholdinhibitionEditFieldValueChanged, true);
            app.ThreasholdinhibitionEditField.Value = 10;
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Inhibited areas')
            app.AvgsizepixelEditFieldLabel = uilabel(app.UIFigure);
            app.AvgsizepixelEditFieldLabel.Text = 'Avg size (pixel)';
            app.AvgsizepixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.AvgsizepixelEditField.Limits = [1 Inf];
            app.AvgsizepixelEditField.ValueDisplayFormat = '%.0f';
            app.AvgsizepixelEditField.ValueChangedFcn = createCallbackFcn(app, @AvgsizepixelEditFieldValueChanged, true);
            app.AvgsizepixelEditField.Value = 5;
            app.laserP = uiaxes(app.UIFigure);
            title(app.laserP, 'Elicited activation with stimulus #n')
            app.running = uiaxes(app.UIFigure);
            title(app.running, 'Running avarage 2D')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Average activated')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Reference')
            app.VisualizestimuliseparatedButton = uibutton(app.UIFigure, 'push');
            app.VisualizestimuliseparatedButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizestimuliseparatedButtonPushed, true);
            app.VisualizestimuliseparatedButton.Text = 'Visualize stimuli separated';
            app.StimulistartingframesEditFieldLabel = uilabel(app.UIFigure);
            app.StimulistartingframesEditFieldLabel.Enable = 'off';
            app.StimulistartingframesEditFieldLabel.Text = 'Stimuli starting frames';
            app.StimulistartingframesEditField = uieditfield(app.UIFigure, 'text');
            app.StimulistartingframesEditField.Editable = 'off';
            app.StimulistartingframesEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subtrFrames(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end