classdef subtraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        subImg                   matlab.ui.control.UIAxes
        thrImg                   matlab.ui.control.UIAxes
        ThreasholdSliderLabel    matlab.ui.control.Label
        ThreasholdSlider         matlab.ui.control.Slider
        FramepreEditFieldLabel   matlab.ui.control.Label
        FramepreEditField        matlab.ui.control.NumericEditField
        FramepostEditFieldLabel  matlab.ui.control.Label
        FramepostEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp % Description
        posROI
        currentThreshData
        frPre
        frPost
        thr
        differenceFr
    end

    methods (Access = private)
    
        function results = plotDiff(app)
            xmin=uint16(app.posROI(1));
            ymin=uint16(app.posROI(2));
            xmax=xmin+uint16(app.posROI(3));
            ymax=ymin+uint16(app.posROI(4));
            
            xlim(app.subImg, [0 xmax-xmin])
            ylim(app.subImg, [0 ymax-ymin])
            xlim(app.thrImg, [0 xmax-xmin])
            ylim(app.thrImg, [0 ymax-ymin])
            
            beforeFr=app.currentThreshData(ymin:ymax,xmin:xmax,app.frPre);
            afterFr=app.currentThreshData(ymin:ymax,xmin:xmax,app.frPost);
            app.differenceFr=afterFr-beforeFr;
            imagesc(app.subImg, app.differenceFr)
            lineFr=reshape(app.differenceFr, [], 1);
            ref=mean(lineFr)+app.thr*sqrt(var(lineFr));
            diffThr=(app.differenceFr>ref);
            imagesc(app.thrImg, diffThr)
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp=mainApp;
            app.posROI=app.CallingApp.posROI;
            app.currentThreshData=app.CallingApp.currentThreshData;
            app.frPre=app.FramepreEditField.Value;
            app.frPost=app.FramepostEditField.Value;
            app.thr=app.ThreasholdSlider.Value;
            app.FramepreEditField.Limits = [1 app.CallingApp.newnumFrames-1];
            app.FramepostEditField.Limits = [2 app.CallingApp.newnumFrames];
            plotDiff(app);
        end

        % Value changed function: FramepreEditField
        function FramepreEditFieldValueChanged(app, event)
            value = app.FramepreEditField.Value;
            if value>app.FramepostEditField.Value
                app.frPre=app.FramepostEditField.Value-1;
            else
                app.frPre=value;
            end 
            app.FramepreEditField.Value=app.frPre;
            plotDiff(app);
        end

        % Value changed function: FramepostEditField
        function FramepostEditFieldValueChanged(app, event)
            value = app.FramepostEditField.Value;
            if value<app.FramepreEditField.Value
                app.frPost=app.FramepreEditField.Value+1;
            else
                app.frPost=value;
            end 
            app.FramepostEditField.Value=app.frPost;
            plotDiff(app);
        end

        % Value changed function: ThreasholdSlider
        function ThreasholdSliderValueChanged(app, event)
            app.thr = app.ThreasholdSlider.Value;
            plotDiff(app);
        end

        % Value changing function: ThreasholdSlider
        function ThreasholdSliderValueChanging(app, event)
            app.thr = event.Value;
            plotDiff(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.subImg = uiaxes(app.UIFigure);
            title(app.subImg, 'Subtracted image')
            app.thrImg = uiaxes(app.UIFigure);
            title(app.thrImg, 'Threashold subtraction')
            app.ThreasholdSliderLabel = uilabel(app.UIFigure);
            app.ThreasholdSliderLabel.Text = 'Threashold';
            app.ThreasholdSlider = uislider(app.UIFigure);
            app.ThreasholdSlider.Limits = [0 5];
            app.ThreasholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThreasholdSliderValueChanged, true);
            app.ThreasholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThreasholdSliderValueChanging, true);
            app.FramepreEditFieldLabel = uilabel(app.UIFigure);
            app.FramepreEditFieldLabel.Text = 'Frame pre';
            app.FramepreEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramepreEditField.Limits = [1 Inf];
            app.FramepreEditField.ValueChangedFcn = createCallbackFcn(app, @FramepreEditFieldValueChanged, true);
            app.FramepreEditField.Value = 1;
            app.FramepostEditFieldLabel = uilabel(app.UIFigure);
            app.FramepostEditFieldLabel.Text = 'Frame post';
            app.FramepostEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramepostEditField.Limits = [1 Inf];
            app.FramepostEditField.ValueChangedFcn = createCallbackFcn(app, @FramepostEditFieldValueChanged, true);
            app.FramepostEditField.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = subtraction(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end